classdef RoboespasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboespasAppUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CapturarTab                     matlab.ui.container.Tab
        MoveralaposicininicialLabel     matlab.ui.control.Label
        PersonalizarposicininicialLabel  matlab.ui.control.Label
        GrabarysalvartrayectoriadeflexinLabel  matlab.ui.control.Label
        IzquierdaButton                 matlab.ui.control.Button
        DerechaButton                   matlab.ui.control.Button
        FreeButtonGroup                 matlab.ui.container.ButtonGroup
        FreeBlockRobotButton            matlab.ui.control.ToggleButton
        FreeXButton                     matlab.ui.control.ToggleButton
        FreeYButton                     matlab.ui.control.ToggleButton
        FreeZButton                     matlab.ui.control.ToggleButton
        CaptureButtonGroup              matlab.ui.container.ButtonGroup
        CaptureBlockRobotButton         matlab.ui.control.ToggleButton
        IniciarButton                   matlab.ui.control.ToggleButton
        FinalizarButton                 matlab.ui.control.ToggleButton
        GenerartrayectoriadeflexoextensinLabel  matlab.ui.control.Label
        AjustarTrayectoriaButton        matlab.ui.control.Button
        InputDropDown                   matlab.ui.control.DropDown
        RepetirTab                      matlab.ui.container.Tab
        AjustesLabel                    matlab.ui.control.Label
        EjecutartrayectoriadereferenciaLabel  matlab.ui.control.Label
        PosicionInicialVacioButton      matlab.ui.control.Button
        EjecutarTrayectoriaVacioButton  matlab.ui.control.Button
        EjecutartrayectoriaLabel        matlab.ui.control.Label
        PosicionInicialRepetirButton    matlab.ui.control.Button
        EjecutarTrayectoriaButton       matlab.ui.control.Button
        RepetitionsSpinner              matlab.ui.control.Spinner
        VelocidadSlider                 matlab.ui.control.Slider
        VelocidadLabel                  matlab.ui.control.Label
        AdjustedDropDown                matlab.ui.control.DropDown
        SeleccionartrayectoriaarepetirLabel  matlab.ui.control.Label
        RelaxMusclesButton2             matlab.ui.control.StateButton
        TentreflexionyextensionsLabel   matlab.ui.control.Label
        MiddlePause                     matlab.ui.control.NumericEditField
        TentreflexoextensionessLabel    matlab.ui.control.Label
        BetweenTrialsPause              matlab.ui.control.Spinner
        EMGsButton                      matlab.ui.control.StateButton
        EMGHelp                         matlab.ui.control.Image
        ResultadosTab                   matlab.ui.container.Tab
        ResultsPanel                    matlab.ui.container.Panel
        TrayectoriaIIWALabel            matlab.ui.control.Label
        ArticularLabel                  matlab.ui.control.Label
        qButton                         matlab.ui.control.Button
        dqButton                        matlab.ui.control.Button
        ErqButton                       matlab.ui.control.Button
        ErdqButton                      matlab.ui.control.Button
        CartesianaLabel                 matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        ErxButton                       matlab.ui.control.Button
        ErdxButton                      matlab.ui.control.Button
        TresDButton                     matlab.ui.control.Button
        dxButton                        matlab.ui.control.Button
        SensorFuerzaParLabel            matlab.ui.control.Label
        FTButton                        matlab.ui.control.Button
        SensoresDelsysLabel             matlab.ui.control.Label
        EMGButton                       matlab.ui.control.Button
        AceleracionesButton             matlab.ui.control.Button
        VelocidadAngularButton          matlab.ui.control.Button
        ddqButton                       matlab.ui.control.Button
        SeleccionelarepeticinamostrarLabel  matlab.ui.control.Label
        RepetitionPlotSpinner           matlab.ui.control.Spinner
        EpxButton                       matlab.ui.control.Button
        EpdxButton                      matlab.ui.control.Button
        RepetibilidadCartesianaLabel    matlab.ui.control.Label
        PrecisionCartesianaLabel        matlab.ui.control.Label
        RepetibilidadArticularLabel     matlab.ui.control.Label
        PrecisionArticularLabel         matlab.ui.control.Label
        EpqButton                       matlab.ui.control.Button
        EpdqButton                      matlab.ui.control.Button
        TqButton                        matlab.ui.control.Button
        FTIIWAButton                    matlab.ui.control.Button
        paracompararlasentresLabel      matlab.ui.control.Label
        PacientePanel                   matlab.ui.container.Panel
        IDSpinnerLabel                  matlab.ui.control.Label
        IDSpinner                       matlab.ui.control.Spinner
        BrazoPanel                      matlab.ui.container.Panel
        ArmSwitch                       matlab.ui.control.Switch
        DLabel                          matlab.ui.control.Label
        ILabel                          matlab.ui.control.Label
        IiwaCommandPanel                matlab.ui.container.Panel
        IiwaCommandSwitch               matlab.ui.control.Switch
        FRILabel                        matlab.ui.control.Label
        StackLabel                      matlab.ui.control.Label
        ConexionesPanel                 matlab.ui.container.Panel
        IIWACheckBox                    matlab.ui.control.CheckBox
        DelsysCheckBox                  matlab.ui.control.CheckBox
        FTSensorCheckBox                matlab.ui.control.CheckBox
        RoboespasLogo                   matlab.ui.control.Image
        UC3MLogo                        matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        RoboespasLogo_2                 matlab.ui.control.Image
        RoboespasLogo_3                 matlab.ui.control.Image
        RoboespasLogo_4                 matlab.ui.control.Image
        RoboespasLogo_5                 matlab.ui.control.Image
        S3BrachioradialisLabel          matlab.ui.control.Label
        S4FlexorCarpiRadialisLabel      matlab.ui.control.Label
        S1BicepsBrachiiLabel            matlab.ui.control.Label
        S2TricepsLateralHeadLabel       matlab.ui.control.Label
        ColocacinSensoresDelsysenBrazoDerechoLabel  matlab.ui.control.Label
        GraficasPanel                   matlab.ui.container.Panel
    end

%% PROPERTIES %%

    %Constant app properties
    properties(Constant)
        RoboespasAppFolderPath = cell2mat(extractBetween(fileparts(which(mfilename)), 1, strfind(fileparts(which(mfilename)), 'RoboespasApp')+strlength('RoboespasApp')));
        TrialsDatabaseFilePath = [cell2mat(extractBetween(fileparts(which(mfilename)), 1, strfind(fileparts(which(mfilename)), 'RoboespasApp')+strlength('RoboespasApp'))), 'Results/RoboespasTrials/trials.mat'];
        TrialsDatabaseBackupFolderPath = [cell2mat(extractBetween(fileparts(which(mfilename)), 1, strfind(fileparts(which(mfilename)), 'RoboespasApp')+strlength('RoboespasApp'))), 'Results/RoboespasTrials/Backups/'];
        ArmReference = ['L', 'R']; % Used to relate L and R with 1 and 2 numbers, possible values of ArmSwitch       
        
        TrajectorySelectStr = {'Seleccione una trayectoria'};
        MovementSelectStr = {'Seleccione un movimiento'};
        
        MaxSensors = 6;
        
        DefaultVelocity = 15; % Repeat all trajectories at 15º/s unless slider is changed
    end
    
    %Variable non-observable properties
    properties (Access = private)
        IIWAControlObject
        FTSensorObject
        DelsysSensorsObject
        TimeWaitBetweenRepetitions = 6 

        TrialsFile
        ExecutionTimes = {};
    end
    
    %Variable observable app properties
    properties (SetObservable)
        Arm % May be 'L' or 'R' 
        IDPatient % Description
        NameMovement = 'flexext'
    end
    
%% FUNCTIONS %%

    %Static functions -> Handle property changed events
    methods (Static)
        function handlePropEvents(src, event)
            switch src.Name
                case 'Arm'
                    event.AffectedObject.ArmChanged();
                case 'IDPatient'
                    event.AffectedObject.IDPatientChanged();
                otherwise
                    ME=MException('Roboespas:HandleError', 'handlePropEvents received a variable whose callback is not defined');
                    throw(ME);
            end
        end
    end
    
    %Created functions
    methods (Access = private)
        
        % Property changed event functions
        function ArmChanged(app)
            app.ArmSwitch.Value=num2str(app.ArmId());
            if (app.Arm=='L')
                app.ILabel.Enable='on';
                app.DLabel.Enable='off';
            elseif(app.Arm=='R')
                app.ILabel.Enable='off';
                app.DLabel.Enable='on';
            else
                ME=MException('Roboespas:WrongArmValue', '"Arm" value must be either "L" or "R".');
                throw(ME);
            end
        end
        
        function IDPatientChanged(app)
            if (isnumeric(app.IDPatient))
                app.IDSpinner.Value=app.IDPatient;
            else
                ME=MException('Roboespas:WrongIDPatientValue', '"IDPatient" value must be numeric.');
                throw(ME);
            end
        end
        
        % Update Dropdowns functions
        function UpdateInputDropDown(app)
            try
                app.InputDropDown.Items=[app.TrajectorySelectStr, app.IIWAControlObject.DataInputList();];
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
  
        function UpdateAdjustedDropDown(app)
            try
                app.AdjustedDropDown.Items=[app.TrajectorySelectStr, app.IIWAControlObject.DataAdjustedList()];
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        
        %Get id from Arm string
        function id = ArmId(app)
            id=find(app.ArmReference==app.Arm);
        end

        function SaveTrial(app, i_trial)
            try
                tic
                [ ~, ~ ] = mkdir(extractBefore(app.TrialsDatabaseFilePath, 'trials.mat'));
                [ ~, ~ ] = mkdir(app.TrialsDatabaseBackupFolderPath);
                if (~isempty(who(app.TrialsFile)))
                    ntrial=size(app.TrialsFile.Trials,1);
                    IDTrial=ntrial+1;
                else
                    IDTrial=1;
                end
                Trial.IDTrial = IDTrial;
                Trial.IDPatient = app.IDPatient;
                Trial.Conditions.NameData = app.IIWAControlObject.NameData;
                Trial.Conditions.Arm = app.Arm;
                Trial.Conditions.ExecutionVelocityDPS = app.VelocidadSlider.Value;
                Trial.Conditions.DateCaptured = app.IIWAControlObject.CapturedDate;
                Trial.Conditions.DateExecuted = datestr(now, 'yymmdd_HHMMSS');
                Trial.Trajectory=app.IIWAControlObject.GetStruct(i_trial);
                Trial.FTSensor=app.FTSensorObject.GetStruct(i_trial);
                Trial.DelsysSensors=app.DelsysSensorsObject.GetStruct(i_trial);
                
                if (~isempty(app.ExecutionTimes))
                    Trial.ExecutionTimes = app.ExecutionTimes{i_trial}; 
                else
                    Trial.ExecutionTimes=[];
                end
                app.TrialsFile.Trials(IDTrial,1) = Trial;
                disp(['Salvado el trial ', num2str(IDTrial)]);
                saveEveryNTrials=1000;
                if (mod(IDTrial,saveEveryNTrials) == 0) %Save backup every trial
                    TrialsBackupFilePath = [app.TrialsDatabaseBackupFolderPath, 'trials', datestr(now, '_yyyy_mm_dd_HH_MM'), '.mat'];
                    [status, msg] = copyfile(app.TrialsDatabaseFilePath, TrialsBackupFilePath, 'f');
                    if (status~=1)
                        uialert(app.RoboespasAppUIFigure, msg, '');
                    else
                        %uialert(app.RoboespasAppUIFigure, ['Backup saved in', TrialsBackupFilePath], '', 'Icon', 'success');
                    end
                end
                toc
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        function ResetRepeatButtons(app)
            app.PosicionInicialRepetirButton.Enable='off';
            app.EjecutarTrayectoriaButton.Enable='off';
            app.PosicionInicialVacioButton.Enable='on';
            app.EjecutarTrayectoriaVacioButton.Enable = 'off';
        end
        function ResetCaptureButtons(app)
            app.CaptureButtonGroup.SelectedObject=app.CaptureBlockRobotButton;
            app.CaptureBlockRobotButton.Enable='off';
            app.IniciarButton.Enable='off';
            app.FinalizarButton.Enable='off';
            app.FreeBlockRobotButton.Enable='off';
            app.FreeXButton.Enable='off';
            app.FreeYButton.Enable='off';
            app.FreeZButton.Enable='off';
            app.IzquierdaButton.Enable='on';
            app.DerechaButton.Enable='on';
        end
        function SetCurrentSliderVelocity(app)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ajustando trayectoria a la velocidad requerida');
            try
                app.VelocidadSlider.Value = round(app.VelocidadSlider.Value/5)*5;
                %v=round(app.VelocidadSlider.Value/app.IIWAControlObject.InputAngularVelocity,2);
                prg.Message=['Ajustando trayectoria a velocidad ', num2str(app.VelocidadSlider.Value), char(176), '/s'];
                app.IIWAControlObject.ChangeVelocity(app.VelocidadSlider.Value);
                app.VelocidadLabel.Text=[num2str(round(app.VelocidadSlider.Value,2)), ' ', char(176), '/s'];
                app.ResetRepeatButtons();
                close(prg);
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function SaveSensorsPosition(app)
            posArray = zeros(app.DelsysSensorsObject.NumSensors,3);
            for i = 1:app.DelsysSensorsObject.NumSensors
                posEditField = sprintf('Sensor%dpos', i);
                value = app.(posEditField).Value;
                valueArray = str2double(split(erase(value, {'[',']'}),{' ',','}));
                posArray(i,:) = valueArray(~isnan(valueArray))';
            end
            app.DelsysSensorsObject.SetSensorsPosition(posArray);
        end
        
%         function SetAyudaFlexExtPanel(app)
%             app.AyudaFlexextPanel.Position = app.TabGroup.Position;
%             app.AyudaFlexextPanel.Position(1) = app.TabGroup.Position(3) + 2*13;
%             gapx = 13;
%             gapy = 10;
%             
%             app.Image3.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning3.svg'];
%             app.Image3.Position(1) = gapx;
%             app.Image3.Position(2) = gapy;
%             app.Image3.Position(3) = (app.AyudaFlexextPanel.InnerPosition(3) - gapx*4)/2;
%             app.Image3.Position(4) = (app.AyudaFlexextPanel.InnerPosition(4) - 2*app.SensorLabel3.Position(4) - 5*gapy)/2;
%             app.SensorLabel3.Text = ['Sensor 3: ', app.DelsysSensorsObject.SensorsMuscles{1,3}];
%             app.SensorLabel3.Position(1) = app.Image3.Position(1);
%             app.SensorLabel3.Position(2) = app.Image3.Position(2) + app.Image3.Position(4) + gapy;
%             
%             app.Image4.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning4.svg'];
%             app.Image4.Position = app.Image3.Position;
%             app.Image4.Position(1) = app.Image3.Position(1) + app.Image3.Position(3) + gapx*2;
%             app.SensorLabel4.Text = ['Sensor 4: ', app.DelsysSensorsObject.SensorsMuscles{1,4}];
%             app.SensorLabel4.Position(1) = app.Image4.Position(1);
%             app.SensorLabel4.Position(2) = app.Image4.Position(2) + app.Image4.Position(4) + gapy;
%             
%             app.Image1.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning1.svg'];
%             app.Image1.Position = app.Image3.Position;
%             app.Image1.Position(2) = app.Image3.Position(2) + + app.Image3.Position(4) + app.SensorLabel3.Position(4) + gapy*2;
%             app.SensorLabel1.Text = ['Sensor 1: ', app.DelsysSensorsObject.SensorsMuscles{1,1}];
%             app.SensorLabel1.Position(1) = app.Image1.Position(1);
%             app.SensorLabel1.Position(2) = app.Image1.Position(2) + app.Image1.Position(4) + gapy;
%             
%             app.Image2.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning2.svg'];
%             app.Image2.Position = app.Image1.Position;
%             app.Image2.Position(1) = app.Image4.Position(1);
%             app.SensorLabel2.Text = ['Sensor 2: ', app.DelsysSensorsObject.SensorsMuscles{1,2}];
%             app.SensorLabel2.Position(1) = app.Image2.Position(1);
%             app.SensorLabel2.Position(2) = app.Image2.Position(2) + app.Image2.Position(4) + gapy;
%         end
   
        function UpdatePanelsPosition(app)
            app.PacientePanel.Position(2) = app.TabGroup.Position(2) + app.TabGroup.Position(4) + 10;
            app.PacientePanel.Position(1) = app.TabGroup.Position(1);
            app.PacientePanel.Position(3) = (app.TabGroup.Position(3)- 3*5)/5;
            drawnow;
            app.IDSpinnerLabel.Position(1) = app.PacientePanel.Position(3)/2 - (app.IDSpinnerLabel.Position(3)+app.IDSpinner.Position(3))/2;
            app.BrazoPanel.Position(2) = app.PacientePanel.Position(2);
            app.BrazoPanel.Position(1) = app.PacientePanel.Position(1) + app.PacientePanel.Position(3) + 5;
            app.BrazoPanel.Position(3) = (app.TabGroup.Position(3)- 3*5)*2/5;
            drawnow;
            app.ILabel.Position(1) = app.BrazoPanel.Position(3)/2 - (app.ILabel.Position(3) + app.ArmSwitch.Position(3) + app.DLabel.Position(3) + 15)/2;
            app.ArmSwitch.Position(1) = app.ILabel.Position(1) + app.ILabel.Position(3) + 5;
            app.DLabel.Position(1) = app.ArmSwitch.Position(1) + app.ArmSwitch.Position(3) + 10;
            app.IiwaCommandPanel.Position(2) = app.PacientePanel.Position(2);
            app.IiwaCommandPanel.Position(1) = app.BrazoPanel.Position(1) + app.BrazoPanel.Position(3) + 5;
            app.IiwaCommandPanel.Position(3) = (app.TabGroup.Position(3) - 3*5)/5;
            drawnow;
            app.StackLabel.Position(1) = app.IiwaCommandPanel.Position(3)/2 - (app.StackLabel.Position(3)+app.IiwaCommandSwitch.Position(3)+app.FRILabel.Position(3)+20)/2;
            app.IiwaCommandSwitch.Position(1) = app.StackLabel.Position(1) + app.StackLabel.Position(3) + 10;
            app.FRILabel.Position(1) = app.IiwaCommandSwitch.Position(1) + app.IiwaCommandSwitch.Position(3) + 10;
            app.ConexionesPanel.Position(2) = app.PacientePanel.Position(2);
            app.ConexionesPanel.Position(1) = app.IiwaCommandPanel.Position(1) + app.IiwaCommandPanel.Position(3) + 5;
            app.ConexionesPanel.Position(3) = (app.TabGroup.Position(3) - 3*5)/5;
            drawnow;
            app.IIWACheckBox.Position(1) = app.ConexionesPanel.Position(3)/2 - app.FTSensorCheckBox.Position(3)/2;
            app.DelsysCheckBox.Position(1) = app.IIWACheckBox.Position(1);
            app.FTSensorCheckBox.Position(1) = app.IIWACheckBox.Position(1);
            app.CloseImage.Position(1) = app.TabGroup.Position(3) - 125;
            app.CloseImage.Position(2) = app.TabGroup.Position(4) - 35;
            app.ResultsPanel.Position(1) = app.TabGroup.Position(1)-13;
            app.ResultsPanel.Position(2) = app.TabGroup.Position(2)-10;
            app.ResultsPanel.Position(3) = app.TabGroup.Position(3)-26;
            app.ResultsPanel.Position(4) = app.TabGroup.Position(4);
        end   
        
        function EnablePlotButtons(app, origin, value)
            if value
                state = 'on';
            else
                state = 'off';
            end
            switch(origin)
                case 'iiwa'
                    app.qButton.Enable = state;
                    app.dqButton.Enable = state;
                    app.ddqButton.Enable = state;                    
                    app.EpqButton.Enable = state;
                    app.EpdqButton.Enable = state;
                    app.TqButton.Enable = state;
                    app.xButton.Enable = state;
                    app.dxButton.Enable = state;
                    app.TresDButton.Enable = state;
                    app.EpxButton.Enable = state;
                    app.EpdxButton.Enable = state;
                case 'ft'
                    app.FTButton.Enable = state;
                case 'delsys'
                    app.EMGButton.Enable = state;
                    app.EMGsButton.Enable = state;
                    app.AceleracionesButton.Enable = state;
                    app.VelocidadAngularButton.Enable = state;                    
            end
        end
        
        function EMGrealTimePlot(app, value)
            if(value)
                app.DelsysSensorsObject.StartEMGplot(app.RepetirTab);
            else
                app.DelsysSensorsObject.StopEMGplot();
            end
        end
        
        function ClearGraficasPanel(app)
            while(~isempty(app.GraficasPanel.Children))
                delete(app.GraficasPanel.Children(1))
            end
        end
    end

%% CALLBACKS %%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando');
            try
                %Visible adjustments
                app.ResultsPanel.Visible='off';
                app.RoboespasAppUIFigure.WindowState = 'maximized';
                app.RoboespasLogo.ImageSource = [fileparts(which(mfilename)), '\Fotos\roboespas_logo.jpg'];
                app.UC3MLogo.ImageSource = [fileparts(which(mfilename)), '\Fotos\uc3m_logo.jpg'];
                drawnow;
                
                % Stablish properties that generate events when modified
                addlistener(app,'Arm','PostSet',@app.handlePropEvents);
                addlistener(app,'IDPatient','PostSet',@app.handlePropEvents);
    
                %Add folders to path
                addpath(genpath(fileparts(which(mfilename))));
                
                %Initialize IIWAControlObject
                app.IIWAControlObject = IIWAControl();
                
                %Initialize FTSensorObject
                app.FTSensorObject = FTSensor(0);
                
                %Initialize DelsysSensors
                app.DelsysSensorsObject = DelsysSensors(0);
                
                %Initialize dropdowns
                app.UpdateInputDropDown();
                app.UpdateAdjustedDropDown();
                
                app.IIWAControlObject.SetMovement(app.NameMovement);
                app.DelsysSensorsObject.SetMovement(app.NameMovement);
                
                %Initialize TrialsFile and IDSpinner
                app.TrialsFile = matfile(app.TrialsDatabaseFilePath, 'Writable', true);
                if (~isempty(who(app.TrialsFile)))
                    lastTrial=app.TrialsFile.Trials(end,1);
                    app.IDSpinner.Value=lastTrial.IDPatient;
                else
                    app.IDSpinner.Value=1;
                end
                
                app.IDSpinner.Value;
                %Initialize variables that need initialization
                app.IDPatient=app.IDSpinner.Value;    
                app.VelocidadLabel.Text = [num2str(app.DefaultVelocity), ' ', char(176), '/s'];
                app.VelocidadSlider.Value = app.DefaultVelocity;
                close(prg);
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderChanged(app, event)
            app.SetCurrentSliderVelocity();
        end

        % Value changed function: IDSpinner
        function IDSpinnerChanged(app, event)
            app.IDPatient = app.IDSpinner.Value;
        end

        % Selection change function: TabGroup
        function TabGroupChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if (selectedTab==app.CapturarTab)
                app.ArmSwitch.Enable='on';
                selection = uiconfirm(app.RoboespasAppUIFigure,'New patient?','Change IDPatient?', 'Options',{'Yes','No'},'Icon', 'question');
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando lista de trayectorias de entrada');
                UpdateInputDropDown(app);
                if (strcmp(selection,'Yes'))
                    app.IDPatient=app.IDPatient+1;
                end
                close(prg);
            elseif (selectedTab==app.RepetirTab)
                app.ArmSwitch.Enable='off';
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando lista de trayectorias ajustadas');
                app.MiddlePause.Value = app.IIWAControlObject.CommandedMiddlePause;
                UpdateAdjustedDropDown(app);
                close(prg);
            elseif (selectedTab==app.ResultadosTab)
                app.ArmSwitch.Enable='off';
                app.ClearGraficasPanel();
            end
        end

        % Callback function
        function SensorsPositionChanged(app, event)
            app.SaveSensorsPosition();
        end

        % Callback function
        function SmoothingValueChanged(app, event)
            app.IIWAControlObject.SetSmoothingParameters(app.CommandedSmoothing.Value);
        end

        % Callback function
        function RelaxMusclesButtonValueChanged(app, event)
            value = app.RelaxMusclesButton.Value;
            if(app.DelsysCheckBox.Value)
                app.EMGrealTimePlot(value);
            else
                app.RelaxMusclesButton.Value = 0;
            end
        end

        % Value changed function: RelaxMusclesButton2
        function RelaxMusclesButton2ValueChanged(app, event)
            value = app.RelaxMusclesButton2.Value;
            if(app.DelsysCheckBox.Value)
                app.EMGrealTimePlot(value);
            else
                app.RelaxMusclesButton2.Value = 0;
            end
        end

        % Button pushed function: IzquierdaButton
        function MoverPrefIzqdaButton(app, event)
            prg = uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendose a posición de referencia izquierda');
            try
                app.IIWAControlObject.SendReferencePositionLeft();
                app.Arm='L';
                app.IniciarButton.Enable='on';
                app.FreeXButton.Enable='on';
                app.FreeYButton.Enable='on';
                app.FreeZButton.Enable='on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición de referencia izquierda', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: DerechaButton
        function MoverPrefDchaButton(app, event)
            prg = uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendose a posición de referencia derecha');
            try
                app.IIWAControlObject.SendReferencePositionRight();
                app.Arm ='R';
                app.IniciarButton.Enable ='on';
                app.FreeXButton.Enable ='on';
                app.FreeYButton.Enable ='on';
                app.FreeZButton.Enable ='on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición de referencia derecha', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Selection changed function: CaptureButtonGroup
        function CaptureButtonGroupSelectionChanged(app, event)
            selectedButton = app.CaptureButtonGroup.SelectedObject;
            if (selectedButton == app.CaptureBlockRobotButton)
                prg = uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Bloqueando robot');
                try
                    app.IIWAControlObject.BlockRobot();
                    app.FinalizarButton.Enable = 'off';
                    app.CaptureBlockRobotButton.Enable = 'off';
                    app.FreeXButton.Enable = 'on';
                    app.FreeYButton.Enable = 'on';
                    app.FreeZButton.Enable = 'on';
                    app.IzquierdaButton.Enable = 'on';
                    app.DerechaButton.Enable = 'on';
                    app.InputDropDown.Enable = 'on';
                    app.AjustarTrayectoriaButton.Enable = 'on';
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, 'Robot bloqueado', '', 'Icon', 'success');                    
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            elseif (selectedButton == app.IniciarButton)
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Iniciando captura');
                try
                    app.IIWAControlObject.CaptureStart( );
                    app.CaptureBlockRobotButton.Enable = 'off';
                    app.IniciarButton.Enable = 'off';
                    app.FinalizarButton.Enable = 'on';
                    app.FreeXButton.Enable = 'off';
                    app.FreeYButton.Enable = 'off';
                    app.FreeZButton.Enable = 'off';
                    app.FreeBlockRobotButton.Enable = 'off';
                    app.IzquierdaButton.Enable = 'off';
                    app.DerechaButton.Enable = 'off';
                    app.InputDropDown.Enable = 'off';
                    app.AjustarTrayectoriaButton.Enable = 'off';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            elseif (selectedButton == app.FinalizarButton)
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Finalizando captura');
                try
                    app.CaptureBlockRobotButton.Enable='on';
                    app.IIWAControlObject.SaveDataInput(app.IDPatient, app.Arm);
                    app.UpdateInputDropDown();
                    app.InputDropDown.Value=app.IIWAControlObject.NameDataInput;
                    app.FinalizarButton.Enable='off';
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, ['Trayectoria grabada guardada en archivo ', app.IIWAControlObject.NameDataInput], '', 'Icon', "success");
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end                
            end
        end

        % Selection changed function: FreeButtonGroup
        function FreeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FreeButtonGroup.SelectedObject;
            if (selectedButton == app.FreeBlockRobotButton)
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Bloqueando robot');
                try
                    app.IIWAControlObject.BlockRobot();
                    app.FreeBlockRobotButton.Enable='off';
                    app.FreeXButton.Enable='on';
                    app.FreeYButton.Enable='on';
                    app.FreeZButton.Enable='on';
                    app.IniciarButton.Enable='on';
                    app.InputDropDown.Enable='on';
                    app.IzquierdaButton.Enable='on';
                    app.DerechaButton.Enable='on';
                    if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                        app.AjustarTrayectoriaButton.Enable='on';
                    end
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, 'Robot bloqueado', '', 'Icon', 'success');
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Liberando robot');
                try
                    if (selectedButton == app.FreeXButton)
                        app.IIWAControlObject.FreeCartesianCoordinate('X');
                    elseif (selectedButton == app.FreeYButton)
                        app.IIWAControlObject.FreeCartesianCoordinate('Y');
                    elseif (selectedButton == app.FreeZButton)
                        app.IIWAControlObject.FreeCartesianCoordinate('Z');
                    end
                    app.FreeXButton.Enable='off';
                    app.FreeYButton.Enable='off';
                    app.FreeZButton.Enable='off';
                    app.FreeBlockRobotButton.Enable='on';
                    app.IniciarButton.Enable='off';
                    app.InputDropDown.Enable='off';
                    app.IzquierdaButton.Enable='off';
                    app.DerechaButton.Enable='off';
                    app.AjustarTrayectoriaButton.Enable='off';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Value changed function: InputDropDown
        function InputDropDownChanged(app, event)
            if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando trayectoria de entrada');
                try
                    [app.IDPatient, app.Arm] = app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                    app.AjustarTrayectoriaButton.Enable='on';
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, ['Cargada trayectoria capturada ', app.InputDropDown.Value], '', 'Icon', 'success'); 
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                app.AjustarTrayectoriaButton.Enable='off';
            end
        end

        % Value changed function: AdjustedDropDown
        function AdjustedDropDownChanged(app, event)
            if (~strcmp(app.AdjustedDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando trayectoria ajustada');
                try
                    [app.IDPatient, app.Arm] = app.IIWAControlObject.LoadAdjusted(app.AdjustedDropDown.Value);
                    if (~strcmp(app.InputDropDown.Value,app.IIWAControlObject.NameDataInput))
                        prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando trayectoria de entrada');
                        app.InputDropDown.Value=app.IIWAControlObject.NameDataInput;
                        [app.IDPatient, app.Arm]=app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                        close(prg);
                    end
                    app.SetCurrentSliderVelocity();
                    app.VelocidadSlider.Enable='on';
                    app.VelocidadLabel.Enable='on';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Button pushed function: AjustarTrayectoriaButton
        function AjustarTrayectoriaButtonPushed(app, event)
            if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ajustando trayectoria');
                 try
                    app.GraficasPanel.Visible = 'on';
                    app.ClearGraficasPanel();
                    [app.IDPatient, app.Arm, e_rms]=app.IIWAControlObject.BuildDataAdjusted(app.InputDropDown.Value, app.GraficasPanel);
                    close(prg);
                    selection = uiconfirm(app.RoboespasAppUIFigure, '¿Guardar trayectoria ajustada?', '', 'Options', {'Si', 'No'});
                    if (strcmp(selection,'Si'))
                        prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Guardando trayectoria ajustada');
                        app.UpdateAdjustedDropDown();
                        app.AdjustedDropDown.Value=app.IIWAControlObject.NameDataAdjusted;
                        app.VelocidadSlider.Value = app.DefaultVelocity;
                        app.VelocidadLabel.Text = [num2str(app.VelocidadSlider.Value), ' ', char(176), '/s'];
                        app.IIWAControlObject.ChangeVelocity(app.DefaultVelocity);
                        app.PosicionInicialVacioButton.Enable='on';
                        app.VelocidadSlider.Enable='on';
                        app.VelocidadLabel.Enable='on';
                        close(prg);
                        uialert(app.RoboespasAppUIFigure, ['Trayectoria ajustada guardada en archivo ', app.IIWAControlObject.NameDataAdjusted], '', 'Icon', 'success');    
                    else
                        app.IIWAControlObject.DeleteAdjustedTrajectory();
                        selection = uiconfirm(app.RoboespasAppUIFigure, '¿Borrar la trayectoria capturada?', '', 'Options', {'Si', 'No'});
                        if (strcmp(selection, 'Si'))
                            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ajustando trayectoria');
                            app.IIWAControlObject.DeleteInputTrajectory();
                            app.UpdateInputDropDown();
                            close(prg);
                            uialert(app.RoboespasAppUIFigure, ['Trayectoria de entrada ', app.IIWAControlObject.NameDataAdjusted, ' eliminada.'], '', 'Icon', 'success');
                        end
                    end
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                 end
            else
                uialert(app.RoboespasAppUIFigure, 'Seleccione una trayectoria primero', 'Error');
            end
        end

        % Button pushed function: PosicionInicialVacioButton
        function PosicionInicialVacioButtonPushed(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWAControlObject.SendInitialPosition();
                app.EjecutarTrayectoriaVacioButton.Enable='on';
                app.RelaxMusclesButton.Enable = 'on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: PosicionInicialRepetirButton
        function PosicionInicialRepetirButtonPushed(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWAControlObject.SendInitialPosition();
                app.EjecutarTrayectoriaButton.Enable='on';
                app.RelaxMusclesButton2.Enable = 'on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EjecutarTrayectoriaVacioButton
        function EjecutarTrayectoriaVacioButtonPushed(app, event)
            if(app.RelaxMusclesButton.Value == 1)
                app.RelaxMusclesButton.Value = 0;
                app.EMGrealTimePlot(app.RelaxMusclesButton.Value);
            end
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve');
            try
                %Inicializar
                app.DelsysSensorsObject.StartSensors();
                app.FTSensorObject.StartSensor();
                
                %Iniciar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensor FT.';
                app.FTSensorObject.StartReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensores Delsys.';
                app.DelsysSensorsObject.StartCapture();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Ejecutando trayectoria!';
                %Ejecutar
                [app.IDPatient, app.Arm] = app.IIWAControlObject.SendReferenceTrajectory();
                %Parar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensores Delsys.';
                app.DelsysSensorsObject.EndReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensor FT.';
                app.FTSensorObject.EndReference();
                %Limpiar captura y parar sensores
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Finalizando...';
                app.DelsysSensorsObject.StopSensors(); %13/07: Changed order Delsys/FT stop sensors
                app.FTSensorObject.StopSensor();
                app.FTSensorObject.GetReference();                
                %GUI update
                app.EjecutarTrayectoriaVacioButton.Enable='off';
                app.PosicionInicialRepetirButton.Enable='on';
                app.RelaxMusclesButton.Enable = 'off';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Trayectoria ejecutada en vacío el robot', 'Title', 'Icon', 'success');
            catch ME
               uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end       
        end

        % Button pushed function: EjecutarTrayectoriaButton
        function EjecutarTrayectoriaButtonPushed(app, event)
            if(app.RelaxMusclesButton2.Value == 1)
                app.RelaxMusclesButton2.Value = 0;
                app.EMGrealTimePlot(app.RelaxMusclesButton2.Value);
            end
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Title', ['Ejecutando trayectoria ', app.IIWAControlObject.NameData]);
            try
                app.IIWAControlObject.ClearTrials();
                app.DelsysSensorsObject.ClearTrials();
                app.FTSensorObject.StartSensor(); %Normally lasts for 4-5 seconds
                app.DelsysSensorsObject.StartSensors();
                for i_trial=1:app.RepetitionsSpinner.Value
                    %Inicializar  
                    str_repeticion=['Repetición ', num2str(i_trial), ' de ', num2str(app.RepetitionsSpinner.Value)];
                    if (i_trial~=1)
                        t_wait = app.TimeWaitBetweenRepetitions-toc;
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso, restante: ', num2str(t_wait)];
                        pause(t_wait); %Pause for N seconds discounting the calculations time
                    end
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria'];
                    prg.Value=((i_trial-1)/(app.RepetitionsSpinner.Value*2)); 
                    
                    %Iniciar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensor FT.'];
                    app.FTSensorObject.StartTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.Init=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensores Delsys.'];
                    app.DelsysSensorsObject.StartCapture();
                    app.ExecutionTimes{i_trial}.DelsysSensors.Init=toc;
                    %Ejecutar
                    app.ExecutionTimes{i_trial}.Robot.Call=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Moviendo robot.'];
                    [app.IDPatient, app.Arm]= app.IIWAControlObject.SendTrialTrajectory(i_trial);
                    app.ExecutionTimes{i_trial}.Robot.End=toc;
                    
                    %Parar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensores Delsys.'];
                    app.DelsysSensorsObject.EndTrial(i_trial);
                    app.ExecutionTimes{i_trial}.DelsysSensors.End=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensor FT.'];
                    app.FTSensorObject.EndTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.End=toc;

                    %Save the capture but not interpolate the data yet 
                    tic;
                    if (i_trial~=app.RepetitionsSpinner.Value)
                        %Move to initial position
                        prg.Message =[str_repeticion ': Moviendo a posición inicial']; 
                        app.IIWAControlObject.SendInitialPosition();
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso'];
                    end
                end
                                    
                %Parar captura FT/DelsysSensors
                app.DelsysSensorsObject.StopSensors();
                app.FTSensorObject.StopSensor();

                
                % Salvar resultados
                for i_trial=1:app.RepetitionsSpinner.Value
                    prg.Value=(((i_trial-1)+app.RepetitionsSpinner.Value)/(app.RepetitionsSpinner.Value*2)); 
                    prg.Message=['Salvando repetición ', num2str(i_trial), ' de ', num2str(app.RepetitionsSpinner.Value)];
                    app.SaveTrial(i_trial);
                end
                prg.Message='Calculando repetibilidad';
                app.IIWAControlObject.GetMinMeanMax();
                %Actualizar GUI
                if (app.RepetitionsSpinner.Value==1)
                    app.RepetitionPlotSpinner.Limits=[1 2];
                    app.RepetitionPlotSpinner.Value=1;
                    app.RepetitionPlotSpinner.Enable='off';
                else
                    app.RepetitionPlotSpinner.Enable='on';
                    app.RepetitionPlotSpinner.Value=1;
                    app.RepetitionPlotSpinner.Limits=[0, app.RepetitionsSpinner.Value];
                end
                app.ResultsPanel.Visible='on';
                app.EjecutarTrayectoriaButton.Enable='off';
                app.RelaxMusclesButton2.Enable = 'off';
                close(prg); 
                uialert(app.RoboespasAppUIFigure, 'Trayectoria ejecutada en el robot', 'Title', 'Icon', 'success', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf);
                app.TabGroup.SelectedTab = app.ResultadosTab;
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: qButton
        function qButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointPositionTrajectories(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dqButton
        function dqButtonPushed(app, event)
            try
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointVelocityTrajectories(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ddqButton
        function ddqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointAccelerationTrajectories( app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErqButton
        function ErqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointPositionRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErdqButton
        function ErdqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpqButton
        function EpqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointPositionError(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpdqButton
        function EpdqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointVelocityError(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionTrajectories(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dxButton
        function dxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityTrajectories(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErxButton
        function ErxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpxButton
        function EpxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionError(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpdxButton
        function EpdxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityError(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: TresDButton
        function TresDButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.Plot3DCartesianPositionTrajectories(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FTButton
        function FTButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.FTSensorObject.PlotFT(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: VelocidadAngularButton
        function VelocidadAngularButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.DelsysSensorsObject.PlotGYRO(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EMGButton
        function EMGButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.DelsysSensorsObject.PlotEMG(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: AceleracionesButton
        function AceleracionesButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.DelsysSensorsObject.PlotACC(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErdxButton
        function ErdxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: TqButton
        function TqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.ClearGraficasPanel();
                drawnow;
                app.IIWAControlObject.PlotJointTorques(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FTIIWAButton
        function FTIIWAButtonPushed(app, event)
            app.IiwaControlObject.PlotCartesianForceTorque(app.RepetitionPlotSpinner.Value, app.GraficasPanel);
        end

        % Close request function: RoboespasAppUIFigure
        function RoboespasAppUIFigureCloseRequest(app, event)
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                delete(app);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: IIWACheckBox
        function IIWACheckBoxChanged(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando IIWA');
            try
                app.EnablePlotButtons('iiwa', app.IIWACheckBox.Value);
                if (app.IIWACheckBox.Value==1)
                    commandMode = app.IIWAControlObject.ConnectROS();
                    if (strcmp(commandMode, 'FRI'))
                        app.IiwaCommandSwitch.Value = 'FRI';
                        app.FRILabel.Enable='on';
                        app.StackLabel.Enable='off';
                    elseif (strcmp(commandMode, 'Stack'))
                        app.IiwaCommandSwitch.Value='Stack';
                        app.FRILabel.Enable='off';
                        app.StackLabel.Enable='on';
                    end
                else
                    app.FRILabel.Enable='off';
                    app.StackLabel.Enable='off';
                end
                app.ResetRepeatButtons();
                app.ResetCaptureButtons();
                prg.close();
            catch ME
                app.IIWACheckBox.Value = 0;
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
            
        end

        % Value changed function: DelsysCheckBox
        function DelsysCheckBoxChanged(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando sensores Delsys');
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                app.DelsysSensorsObject = DelsysSensors(app.DelsysCheckBox.Value);
                app.DelsysSensorsObject.SetMovement(app.NameMovement);
                app.EnablePlotButtons('delsys', app.DelsysCheckBox.Value);
                prg.close();
            catch ME
                app.DelsysCheckBox.Value = 0;
                prg.close();
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
                
        end

        % Value changed function: FTSensorCheckBox
        function FTSensorCheckBoxChanged(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando sensor F/T');
            try
                app.FTSensorObject = FTSensor(app.FTSensorCheckBox.Value);
                app.EnablePlotButtons('ft', app.FTSensorCheckBox.Value);
                prg.close();
            catch ME
                app.FTSensorCheckBox.Value = 0;
                prg.close();
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
            
        end

        % Value changed function: RepetitionPlotSpinner
        function RepetitionPlotSpinnerValueChanged(app, event)
            value = app.RepetitionPlotSpinner.Value;
            if (value == 0) && (app.IIWACheckBox.Value == 1)
                app.ErqButton.Enable = 'on';
                app.ErdqButton.Enable = 'on';
                app.ErxButton.Enable = 'on';
                app.ErdxButton.Enable = 'on';
            else
                app.ErqButton.Enable = 'off';
                app.ErdqButton.Enable = 'off';
                app.ErxButton.Enable = 'off';
                app.ErdxButton.Enable = 'off';
            end
        end

        % Callback function
        function CloseImageClicked(app, event)
            app.TabGroup.SelectedTab = app.ResultadosTab;
            app.TabGroupChanged();
        end

        % Callback function
        function SegmentsValueChanged(app, event)
            app.IIWAControlObject.SetCommandedPPSegments(app.CommandedSegments.Value);      
        end

        % Value changed function: MiddlePause
        function MiddlePauseValueChanged(app, event)
            app.IIWAControlObject.SetCommandedMiddlePause(app.MiddlePause.Value);
        end

        % Value changed function: BetweenTrialsPause
        function PauseBetweenTrialsChanged(app, event)
            app.TimeWaitBetweenRepetitions = app.BetweenTrialsPause.Value;
        end

        % Callback function
        function DeleteInputTrajectory(app, event)
            app.IIWAControlObject.DeleteInputTrajectory();
            app.UpdateInputDropDown();
        end

        % Callback function
        function DeleteAdjustedTrajectory(app, event)
            app.IIWAControlObject.DeleteAdjustedTrajectory();
            app.UpdateAdjustedDropDown();
        end

        % Value changed function: IiwaCommandSwitch
        function IiwaCommandSwitchValueChanged(app, event)
            if(strcmp(app.IIWAControlObject.CommandMode, 'Stack'))
                app.IIWAControlObject.CommandMode='FRI';
            elseif (strcmp(app.IIWAControlObject.CommandMode, 'FRI'))
                app.IIWAControlObject.CommandMode='Stack';
            else
                uialert(app.RoboespasAppUIFigure, 'Wrong initial CommandMode in IIWAControlObject', 'Error'); 
            end
        end

        % Value changed function: EMGsButton
        function EMGsButtonValueChanged(app, event)
            value = app.EMGsButton.Value;
            if(app.DelsysCheckBox.Value)
                app.EMGrealTimePlot(value);
            else
                app.EMGsButton.Value = 0;
            end
        end

        % Image clicked function: EMGHelp
        function EMGHelpImageClicked(app, event)
            app.GraficasPanel.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoboespasAppUIFigure = uifigure('Visible', 'off');
            app.RoboespasAppUIFigure.Name = 'RoboespasApp';
            app.RoboespasAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoboespasAppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.RoboespasAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupChanged, true);
            app.CapturarTab = uitab(app.TabGroup);
            app.CapturarTab.Title = 'Capturar';
            app.MoveralaposicininicialLabel = uilabel(app.CapturarTab);
            app.MoveralaposicininicialLabel.Text = '1. Mover a la posición inicial';
            app.PersonalizarposicininicialLabel = uilabel(app.CapturarTab);
            app.PersonalizarposicininicialLabel.Text = '2. Personalizar posición inicial';
            app.GrabarysalvartrayectoriadeflexinLabel = uilabel(app.CapturarTab);
            app.GrabarysalvartrayectoriadeflexinLabel.Text = '3. Grabar y salvar trayectoria de flexión';
            app.IzquierdaButton = uibutton(app.CapturarTab, 'push');
            app.IzquierdaButton.ButtonPushedFcn = createCallbackFcn(app, @MoverPrefIzqdaButton, true);
            app.IzquierdaButton.Text = 'Izquierda';
            app.DerechaButton = uibutton(app.CapturarTab, 'push');
            app.DerechaButton.ButtonPushedFcn = createCallbackFcn(app, @MoverPrefDchaButton, true);
            app.DerechaButton.Text = 'Derecha';
            app.FreeButtonGroup = uibuttongroup(app.CapturarTab);
            app.FreeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreeButtonGroupSelectionChanged, true);
            app.FreeBlockRobotButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeBlockRobotButton.Enable = 'off';
            app.FreeBlockRobotButton.Text = 'Bloquear';
            app.FreeBlockRobotButton.Value = true;
            app.FreeXButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeXButton.Enable = 'off';
            app.FreeXButton.Text = 'Liberar X';
            app.FreeYButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeYButton.Enable = 'off';
            app.FreeYButton.Text = 'Liberar Y';
            app.FreeZButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeZButton.Enable = 'off';
            app.FreeZButton.Text = 'Liberar Z';
            app.CaptureButtonGroup = uibuttongroup(app.CapturarTab);
            app.CaptureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaptureButtonGroupSelectionChanged, true);
            app.CaptureBlockRobotButton = uitogglebutton(app.CaptureButtonGroup);
            app.CaptureBlockRobotButton.Enable = 'off';
            app.CaptureBlockRobotButton.Text = 'Bloquear';
            app.CaptureBlockRobotButton.Value = true;
            app.IniciarButton = uitogglebutton(app.CaptureButtonGroup);
            app.IniciarButton.Enable = 'off';
            app.IniciarButton.Text = 'Iniciar';
            app.FinalizarButton = uitogglebutton(app.CaptureButtonGroup);
            app.FinalizarButton.Enable = 'off';
            app.FinalizarButton.Text = 'Finalizar';
            app.GenerartrayectoriadeflexoextensinLabel = uilabel(app.CapturarTab);
            app.GenerartrayectoriadeflexoextensinLabel.Text = '4. Generar trayectoria de flexo-extensión';
            app.AjustarTrayectoriaButton = uibutton(app.CapturarTab, 'push');
            app.AjustarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @AjustarTrayectoriaButtonPushed, true);
            app.AjustarTrayectoriaButton.Enable = 'off';
            app.AjustarTrayectoriaButton.Text = 'Ajustar Trayectoria';
            app.InputDropDown = uidropdown(app.CapturarTab);
            app.InputDropDown.Items = {'Seleccionar trayectoria'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownChanged, true);
            app.InputDropDown.Value = 'Seleccionar trayectoria';
            app.RepetirTab = uitab(app.TabGroup);
            app.RepetirTab.Title = 'Repetir';
            app.AjustesLabel = uilabel(app.RepetirTab);
            app.AjustesLabel.Text = '2. Ajustes';
            app.EjecutartrayectoriadereferenciaLabel = uilabel(app.RepetirTab);
            app.EjecutartrayectoriadereferenciaLabel.Text = '3. Ejecutar trayectoria de referencia';
            app.PosicionInicialVacioButton = uibutton(app.RepetirTab, 'push');
            app.PosicionInicialVacioButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialVacioButtonPushed, true);
            app.PosicionInicialVacioButton.Enable = 'off';
            app.PosicionInicialVacioButton.Text = 'Mandar a la Posición Inicial';
            app.EjecutarTrayectoriaVacioButton = uibutton(app.RepetirTab, 'push');
            app.EjecutarTrayectoriaVacioButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarTrayectoriaVacioButtonPushed, true);
            app.EjecutarTrayectoriaVacioButton.Enable = 'off';
            app.EjecutarTrayectoriaVacioButton.Text = 'Ejecutar Trayectoria en Vacío';
            app.EjecutartrayectoriaLabel = uilabel(app.RepetirTab);
            app.EjecutartrayectoriaLabel.Text = '4. Ejecutar trayectoria';
            app.PosicionInicialRepetirButton = uibutton(app.RepetirTab, 'push');
            app.PosicionInicialRepetirButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialRepetirButtonPushed, true);
            app.PosicionInicialRepetirButton.Enable = 'off';
            app.PosicionInicialRepetirButton.Text = 'Mandar a la Posición Inicial';
            app.EjecutarTrayectoriaButton = uibutton(app.RepetirTab, 'push');
            app.EjecutarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarTrayectoriaButtonPushed, true);
            app.EjecutarTrayectoriaButton.Enable = 'off';
            app.EjecutarTrayectoriaButton.Text = 'Ejecutar Trayectoria';
            app.RepetitionsSpinner = uispinner(app.RepetirTab);
            app.RepetitionsSpinner.Limits = [1 100];
            app.RepetitionsSpinner.RoundFractionalValues = 'on';
            app.RepetitionsSpinner.Value = 1;
            app.VelocidadSlider = uislider(app.RepetirTab);
            app.VelocidadSlider.Limits = [0 45];
            app.VelocidadSlider.MajorTicks = [0 30 60 90 120 150 180 210];
            app.VelocidadSlider.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180', '210'};
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderChanged, true);
            app.VelocidadSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210];
            app.VelocidadSlider.Enable = 'off';
            app.VelocidadSlider.Value = 45;
            app.VelocidadLabel = uilabel(app.RepetirTab);
            app.VelocidadLabel.Enable = 'off';
            app.VelocidadLabel.Text = 'º/s';
            app.AdjustedDropDown = uidropdown(app.RepetirTab);
            app.AdjustedDropDown.Items = {'Seleccionar trayectoria'};
            app.AdjustedDropDown.ValueChangedFcn = createCallbackFcn(app, @AdjustedDropDownChanged, true);
            app.AdjustedDropDown.Value = 'Seleccionar trayectoria';
            app.SeleccionartrayectoriaarepetirLabel = uilabel(app.RepetirTab);
            app.SeleccionartrayectoriaarepetirLabel.Text = '1. Seleccionar trayectoria a repetir';
            app.RelaxMusclesButton2 = uibutton(app.RepetirTab, 'state');
            app.RelaxMusclesButton2.ValueChangedFcn = createCallbackFcn(app, @RelaxMusclesButton2ValueChanged, true);
            app.RelaxMusclesButton2.Enable = 'off';
            app.RelaxMusclesButton2.Visible = 'off';
            app.RelaxMusclesButton2.Text = 'EMG';
            app.TentreflexionyextensionsLabel = uilabel(app.RepetirTab);
            app.TentreflexionyextensionsLabel.Text = 'T entre flexion y extension (s)';
            app.MiddlePause = uieditfield(app.RepetirTab, 'numeric');
            app.MiddlePause.ValueChangedFcn = createCallbackFcn(app, @MiddlePauseValueChanged, true);
            app.TentreflexoextensionessLabel = uilabel(app.RepetirTab);
            app.TentreflexoextensionessLabel.Text = 'T entre flexo-extensiones (s)';
            app.BetweenTrialsPause = uispinner(app.RepetirTab);
            app.BetweenTrialsPause.Step = 0.1;
            app.BetweenTrialsPause.Limits = [0 60];
            app.BetweenTrialsPause.ValueChangedFcn = createCallbackFcn(app, @PauseBetweenTrialsChanged, true);
            app.BetweenTrialsPause.Value = 6;
            app.EMGsButton = uibutton(app.RepetirTab, 'state');
            app.EMGsButton.ValueChangedFcn = createCallbackFcn(app, @EMGsButtonValueChanged, true);
            app.EMGsButton.Enable = 'off';
            app.EMGsButton.Text = 'EMGs';
            app.EMGHelp = uiimage(app.RepetirTab);
            app.EMGHelp.ImageClickedFcn = createCallbackFcn(app, @EMGHelpImageClicked, true);
            app.EMGHelp.ImageSource = 'EMG.png';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.ResultsPanel = uipanel(app.ResultadosTab);
            app.TrayectoriaIIWALabel = uilabel(app.ResultsPanel);
            app.TrayectoriaIIWALabel.Text = 'Trayectoria IIWA';
            app.ArticularLabel = uilabel(app.ResultsPanel);
            app.ArticularLabel.Text = 'Articular';
            app.qButton = uibutton(app.ResultsPanel, 'push');
            app.qButton.ButtonPushedFcn = createCallbackFcn(app, @qButtonPushed, true);
            app.qButton.Enable = 'off';
            app.qButton.Tooltip = {'Posición articular'};
            app.qButton.Text = 'q';
            app.dqButton = uibutton(app.ResultsPanel, 'push');
            app.dqButton.ButtonPushedFcn = createCallbackFcn(app, @dqButtonPushed, true);
            app.dqButton.Enable = 'off';
            app.dqButton.Tooltip = {'Velocidad articular'};
            app.dqButton.Text = 'dq';
            app.ErqButton = uibutton(app.ResultsPanel, 'push');
            app.ErqButton.ButtonPushedFcn = createCallbackFcn(app, @ErqButtonPushed, true);
            app.ErqButton.Enable = 'off';
            app.ErqButton.Tooltip = {'Error de repetibilidad de posición articular entre trayectorias ejecutadas'};
            app.ErqButton.Text = 'Er(q)';
            app.ErdqButton = uibutton(app.ResultsPanel, 'push');
            app.ErdqButton.ButtonPushedFcn = createCallbackFcn(app, @ErdqButtonPushed, true);
            app.ErdqButton.Enable = 'off';
            app.ErdqButton.Tooltip = {'Error de repetibilidad de velocidad articular entre trayectorias ejecutadas'};
            app.ErdqButton.Text = 'Er(dq)';
            app.CartesianaLabel = uilabel(app.ResultsPanel);
            app.CartesianaLabel.Text = 'Cartesiana';
            app.xButton = uibutton(app.ResultsPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Enable = 'off';
            app.xButton.Tooltip = {'Posición cartesiana'};
            app.xButton.Text = 'x';
            app.ErxButton = uibutton(app.ResultsPanel, 'push');
            app.ErxButton.ButtonPushedFcn = createCallbackFcn(app, @ErxButtonPushed, true);
            app.ErxButton.Enable = 'off';
            app.ErxButton.Tooltip = {'Error de repetibilidad de posición cartesiana entre trayectorias ejecutadas'};
            app.ErxButton.Text = 'Er(x)';
            app.ErdxButton = uibutton(app.ResultsPanel, 'push');
            app.ErdxButton.ButtonPushedFcn = createCallbackFcn(app, @ErdxButtonPushed, true);
            app.ErdxButton.Enable = 'off';
            app.ErdxButton.Tooltip = {'Error de repetibilidad de velocidad cartesiana entre trayectorias ejecutadas'};
            app.ErdxButton.Text = 'Er(dx)';
            app.TresDButton = uibutton(app.ResultsPanel, 'push');
            app.TresDButton.ButtonPushedFcn = createCallbackFcn(app, @TresDButtonPushed, true);
            app.TresDButton.Enable = 'off';
            app.TresDButton.Tooltip = {'Posición cartesiana en 3D'};
            app.TresDButton.Text = '3D';
            app.dxButton = uibutton(app.ResultsPanel, 'push');
            app.dxButton.ButtonPushedFcn = createCallbackFcn(app, @dxButtonPushed, true);
            app.dxButton.Enable = 'off';
            app.dxButton.Tooltip = {'Velocidad cartesiana'};
            app.dxButton.Text = 'dx';
            app.SensorFuerzaParLabel = uilabel(app.ResultsPanel);
            app.SensorFuerzaParLabel.Text = 'Sensor Fuerza Par';
            app.FTButton = uibutton(app.ResultsPanel, 'push');
            app.FTButton.ButtonPushedFcn = createCallbackFcn(app, @FTButtonPushed, true);
            app.FTButton.Enable = 'off';
            app.FTButton.Text = 'FT';
            app.SensoresDelsysLabel = uilabel(app.ResultsPanel);
            app.SensoresDelsysLabel.Text = 'Sensores Delsys';
            app.EMGButton = uibutton(app.ResultsPanel, 'push');
            app.EMGButton.ButtonPushedFcn = createCallbackFcn(app, @EMGButtonPushed, true);
            app.EMGButton.Enable = 'off';
            app.EMGButton.Text = 'EMG';
            app.AceleracionesButton = uibutton(app.ResultsPanel, 'push');
            app.AceleracionesButton.ButtonPushedFcn = createCallbackFcn(app, @AceleracionesButtonPushed, true);
            app.AceleracionesButton.Enable = 'off';
            app.AceleracionesButton.Text = 'Aceleraciones';
            app.VelocidadAngularButton = uibutton(app.ResultsPanel, 'push');
            app.VelocidadAngularButton.ButtonPushedFcn = createCallbackFcn(app, @VelocidadAngularButtonPushed, true);
            app.VelocidadAngularButton.Enable = 'off';
            app.VelocidadAngularButton.Text = 'Velocidad Angular';
            app.ddqButton = uibutton(app.ResultsPanel, 'push');
            app.ddqButton.ButtonPushedFcn = createCallbackFcn(app, @ddqButtonPushed, true);
            app.ddqButton.Enable = 'off';
            app.ddqButton.Tooltip = {'Aceleración articular'};
            app.ddqButton.Text = 'ddq';
            app.SeleccionelarepeticinamostrarLabel = uilabel(app.ResultsPanel);
            app.SeleccionelarepeticinamostrarLabel.Text = 'Seleccione la repetición a mostrar';
            app.RepetitionPlotSpinner = uispinner(app.ResultsPanel);
            app.RepetitionPlotSpinner.Limits = [1 10];
            app.RepetitionPlotSpinner.RoundFractionalValues = 'on';
            app.RepetitionPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @RepetitionPlotSpinnerValueChanged, true);
            app.RepetitionPlotSpinner.Tooltip = {'Seleccione ''0'' para comparar todas las repeticiones entre si (repetibilidad) y con la trayectoria comandada (precision).'};
            app.RepetitionPlotSpinner.Value = 1;
            app.EpxButton = uibutton(app.ResultsPanel, 'push');
            app.EpxButton.ButtonPushedFcn = createCallbackFcn(app, @EpxButtonPushed, true);
            app.EpxButton.Enable = 'off';
            app.EpxButton.Tooltip = {'Error de posición cartesiana entre la trayectoria comandada y la ejecutada'};
            app.EpxButton.Text = 'Ep(x)';
            app.EpdxButton = uibutton(app.ResultsPanel, 'push');
            app.EpdxButton.ButtonPushedFcn = createCallbackFcn(app, @EpdxButtonPushed, true);
            app.EpdxButton.Enable = 'off';
            app.EpdxButton.Tooltip = {'Error de velocidad cartesiana entre la trayectoria comandada y la ejecutada'};
            app.EpdxButton.Text = 'Ep(dx)';
            app.RepetibilidadCartesianaLabel = uilabel(app.ResultsPanel);
            app.RepetibilidadCartesianaLabel.Tooltip = {'Si se ha seleccionado la repeticion ''0'''; ' muestra el error cartesiano minimo'; ' maximo y medio entre la trayect'};
            app.RepetibilidadCartesianaLabel.Text = 'Repetibilidad: ';
            app.PrecisionCartesianaLabel = uilabel(app.ResultsPanel);
            app.PrecisionCartesianaLabel.Tooltip = {'Para cada trayectoria muestra el error cartesiano entre la misma y la trayectoria comandada. Si se ha seleccionado la repeticion ''0''  muestra el error cartesiano entre la trayectoria media seguida y la trayectoria comandada.'};
            app.PrecisionCartesianaLabel.Text = 'Precisión:';
            app.RepetibilidadArticularLabel = uilabel(app.ResultsPanel);
            app.RepetibilidadArticularLabel.Tooltip = {'Si se ha seleccionado la repeticion ''0'''; ' muestra el error articular minimo'; ' maximo y medio entre la trayectoria media ejecutada y cada una de las repeticiones ejecutadas. '};
            app.RepetibilidadArticularLabel.Text = 'Repetibilidad:';
            app.PrecisionArticularLabel = uilabel(app.ResultsPanel);
            app.PrecisionArticularLabel.Tooltip = {'Para cada trayectoria'; ' calcula el error articularentre la misma y la trayectoria comandada. Si se ha seleccionado la repeticion ''0'' muestra el error articular entre la  trayectoria media seguida y la trayectoria comandada.'};
            app.PrecisionArticularLabel.Text = 'Precisión:';
            app.EpqButton = uibutton(app.ResultsPanel, 'push');
            app.EpqButton.ButtonPushedFcn = createCallbackFcn(app, @EpqButtonPushed, true);
            app.EpqButton.Enable = 'off';
            app.EpqButton.Tooltip = {'Error de posición articular entre la trayectoria comandada y la ejecutada'};
            app.EpqButton.Text = 'Ep(q)';
            app.EpdqButton = uibutton(app.ResultsPanel, 'push');
            app.EpdqButton.ButtonPushedFcn = createCallbackFcn(app, @EpdqButtonPushed, true);
            app.EpdqButton.Enable = 'off';
            app.EpdqButton.Tooltip = {'Error de velocidad articular entre la trayectoria comandada y la ejecutada'};
            app.EpdqButton.Text = 'Ep(dq)';
            app.TqButton = uibutton(app.ResultsPanel, 'push');
            app.TqButton.ButtonPushedFcn = createCallbackFcn(app, @TqButtonPushed, true);
            app.TqButton.Enable = 'off';
            app.TqButton.Text = 'Tq';
            app.FTIIWAButton = uibutton(app.ResultsPanel, 'push');
            app.FTIIWAButton.ButtonPushedFcn = createCallbackFcn(app, @FTIIWAButtonPushed, true);
            app.FTIIWAButton.Enable = 'off';
            app.FTIIWAButton.Text = 'F/T';
            app.paracompararlasentresLabel = uilabel(app.ResultsPanel);
            app.paracompararlasentresLabel.Text = '(0 para compararlas entre sí)';
            app.PacientePanel = uipanel(app.RoboespasAppUIFigure);
            app.PacientePanel.Title = 'Paciente';
            app.IDSpinnerLabel = uilabel(app.PacientePanel);
            app.IDSpinnerLabel.Enable = 'off';
            app.IDSpinnerLabel.Text = 'ID';
            app.IDSpinner = uispinner(app.PacientePanel);
            app.IDSpinner.Limits = [1 100];
            app.IDSpinner.RoundFractionalValues = 'on';
            app.IDSpinner.ValueChangedFcn = createCallbackFcn(app, @IDSpinnerChanged, true);
            app.IDSpinner.Enable = 'off';
            app.IDSpinner.Value = 1;
            app.BrazoPanel = uipanel(app.RoboespasAppUIFigure);
            app.BrazoPanel.Title = 'Brazo ';
            app.ArmSwitch = uiswitch(app.BrazoPanel, 'slider');
            app.ArmSwitch.Items = {'I', ' D'};
            app.ArmSwitch.Enable = 'off';
            app.ArmSwitch.Value = 'I';
            app.DLabel = uilabel(app.BrazoPanel);
            app.DLabel.Enable = 'off';
            app.DLabel.Text = 'D';
            app.ILabel = uilabel(app.BrazoPanel);
            app.ILabel.Enable = 'off';
            app.ILabel.Text = 'I';
            app.IiwaCommandPanel = uipanel(app.RoboespasAppUIFigure);
            app.IiwaCommandPanel.Title = 'IiwaCommand';
            app.IiwaCommandSwitch = uiswitch(app.IiwaCommandPanel, 'slider');
            app.IiwaCommandSwitch.Items = {'Stack', 'FRI'};
            app.IiwaCommandSwitch.ValueChangedFcn = createCallbackFcn(app, @IiwaCommandSwitchValueChanged, true);
            app.IiwaCommandSwitch.Enable = 'off';
            app.IiwaCommandSwitch.Value = 'FRI';
            app.FRILabel = uilabel(app.IiwaCommandPanel);
            app.FRILabel.Enable = 'off';
            app.FRILabel.Text = 'FRI';
            app.StackLabel = uilabel(app.IiwaCommandPanel);
            app.StackLabel.Enable = 'off';
            app.StackLabel.Text = 'Stack';
            app.ConexionesPanel = uipanel(app.RoboespasAppUIFigure);
            app.ConexionesPanel.Title = 'Conexiones';
            app.IIWACheckBox = uicheckbox(app.ConexionesPanel);
            app.IIWACheckBox.ValueChangedFcn = createCallbackFcn(app, @IIWACheckBoxChanged, true);
            app.IIWACheckBox.Text = 'IIWA';
            app.DelsysCheckBox = uicheckbox(app.ConexionesPanel);
            app.DelsysCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelsysCheckBoxChanged, true);
            app.DelsysCheckBox.Text = 'Delsys';
            app.FTSensorCheckBox = uicheckbox(app.ConexionesPanel);
            app.FTSensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @FTSensorCheckBoxChanged, true);
            app.FTSensorCheckBox.Text = 'FTSensor';
            app.RoboespasLogo = uiimage(app.RoboespasAppUIFigure);
            app.RoboespasLogo.ImageSource = 'roboespas_logo.jpg';
            app.UC3MLogo = uiimage(app.RoboespasAppUIFigure);
            app.UC3MLogo.ImageSource = 'uc3m_logo.jpg';
            app.Panel = uipanel(app.RoboespasAppUIFigure);
            app.RoboespasLogo_2 = uiimage(app.Panel);
            app.RoboespasLogo_2.ImageSource = 'S1.PNG';
            app.RoboespasLogo_3 = uiimage(app.Panel);
            app.RoboespasLogo_3.ImageSource = 'S2.PNG';
            app.RoboespasLogo_4 = uiimage(app.Panel);
            app.RoboespasLogo_4.ImageSource = 'S3.PNG';
            app.RoboespasLogo_5 = uiimage(app.Panel);
            app.RoboespasLogo_5.ImageSource = 'S4.PNG';
            app.S3BrachioradialisLabel = uilabel(app.Panel);
            app.S3BrachioradialisLabel.Text = 'S3 - Brachioradialis';
            app.S4FlexorCarpiRadialisLabel = uilabel(app.Panel);
            app.S4FlexorCarpiRadialisLabel.Text = 'S4 - Flexor Carpi Radialis';
            app.S1BicepsBrachiiLabel = uilabel(app.Panel);
            app.S1BicepsBrachiiLabel.Text = 'S1 - Biceps Brachii';
            app.S2TricepsLateralHeadLabel = uilabel(app.Panel);
            app.S2TricepsLateralHeadLabel.Text = 'S2 - Triceps Lateral Head';
            app.ColocacinSensoresDelsysenBrazoDerechoLabel = uilabel(app.Panel);
            app.ColocacinSensoresDelsysenBrazoDerechoLabel.Text = 'Colocación Sensores Delsys en Brazo Derecho';
            app.GraficasPanel = uipanel(app.RoboespasAppUIFigure);
            app.GraficasPanel.Visible = 'off';
            % Show the figure after all components are created
            app.RoboespasAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboespasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboespasAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboespasAppUIFigure)
        end
    end
end