classdef ROBOESPAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InfoSesionTab                   matlab.ui.container.Tab
        MovementDropDown                matlab.ui.control.DropDown
        SeleccionarMovimientoLabel      matlab.ui.control.Label
        PosicionSensoresLabel           matlab.ui.control.Label
        Sensor1pos                      matlab.ui.control.EditField
        Sensor1Label                    matlab.ui.control.Label
        Sensor2pos                      matlab.ui.control.EditField
        Sensor2Label                    matlab.ui.control.Label
        Sensor3pos                      matlab.ui.control.EditField
        Sensor3Label                    matlab.ui.control.Label
        Sensor4pos                      matlab.ui.control.EditField
        Sensor4Label                    matlab.ui.control.Label
        Sensor5pos                      matlab.ui.control.EditField
        Sensor5Label                    matlab.ui.control.Label
        Sensor6pos                      matlab.ui.control.EditField
        Sensor6Label                    matlab.ui.control.Label
        AyudaButton                     matlab.ui.control.StateButton
        CapturarTab                     matlab.ui.container.Tab
        MoveraPosicinInicialdeReferenciaLabel  matlab.ui.control.Label
        PersonalizarPosicinInicialLabel  matlab.ui.control.Label
        GrabarysalvartrayectoriadeflexinLabel  matlab.ui.control.Label
        IzquierdaButton                 matlab.ui.control.Button
        DerechaButton                   matlab.ui.control.Button
        FreeButtonGroup                 matlab.ui.container.ButtonGroup
        FreeBlockRobotButton            matlab.ui.control.ToggleButton
        FreeXButton                     matlab.ui.control.ToggleButton
        FreeYButton                     matlab.ui.control.ToggleButton
        FreeZButton                     matlab.ui.control.ToggleButton
        CaptureButtonGroup              matlab.ui.container.ButtonGroup
        CaptureBlockRobotButton         matlab.ui.control.ToggleButton
        IniciarButton                   matlab.ui.control.ToggleButton
        FinalizarButton                 matlab.ui.control.ToggleButton
        GenerarysalvartrayectoriadeflexoextensinLabel  matlab.ui.control.Label
        AjustarTrayectoriaButton        matlab.ui.control.Button
        InputDropDown                   matlab.ui.control.DropDown
        ConfigurarButton                matlab.ui.control.StateButton
        ConfigurarajustedetrayectoriaLabel  matlab.ui.control.Label
        RepetirTab                      matlab.ui.container.Tab
        ModificarVelocidadLabel         matlab.ui.control.Label
        CalibrarSensorFTparaestaTrayectoriaLabel  matlab.ui.control.Label
        PosicionInicialVacioButton      matlab.ui.control.Button
        EjecutarTrayectoriaVacioButton  matlab.ui.control.Button
        EjecutarTrayectoriaLabel        matlab.ui.control.Label
        PosicionInicialRepetirButton    matlab.ui.control.Button
        EjecutarTrayectoriaButton       matlab.ui.control.Button
        RepetitionsSpinner              matlab.ui.control.Spinner
        VelocidadSlider                 matlab.ui.control.Slider
        VelocidadLabel                  matlab.ui.control.Label
        AdjustedDropDown                matlab.ui.control.DropDown
        SeleccionartrayectoriaarepetirLabel  matlab.ui.control.Label
        RelaxMusclesButton              matlab.ui.control.StateButton
        RelaxMusclesButton2             matlab.ui.control.StateButton
        ResultadosTab                   matlab.ui.container.Tab
        ResultsPanel                    matlab.ui.container.Panel
        TrayectoriaLabel                matlab.ui.control.Label
        ArticularLabel                  matlab.ui.control.Label
        qButton                         matlab.ui.control.Button
        dqButton                        matlab.ui.control.Button
        EqButton                        matlab.ui.control.Button
        EdqButton                       matlab.ui.control.Button
        CartesianaLabel                 matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        ExButton                        matlab.ui.control.Button
        EdxButton                       matlab.ui.control.Button
        TresDButton                     matlab.ui.control.Button
        dxButton                        matlab.ui.control.Button
        SensorFuerzaParLabel            matlab.ui.control.Label
        FTButton                        matlab.ui.control.Button
        SensoresDelsysLabel             matlab.ui.control.Label
        EMGButton                       matlab.ui.control.Button
        AceleracionesButton             matlab.ui.control.Button
        VelocidadAngularButton          matlab.ui.control.Button
        ddqButton                       matlab.ui.control.Button
        SeleccionelarepeticinamostrarLabel  matlab.ui.control.Label
        RepetitionPlotSpinner           matlab.ui.control.Spinner
        EpxButton                       matlab.ui.control.Button
        EpdxButton                      matlab.ui.control.Button
        ExactitudLabel                  matlab.ui.control.Label
        PrecisinLabel                   matlab.ui.control.Label
        ExactitudLabel_2                matlab.ui.control.Label
        PrecisinLabel_2                 matlab.ui.control.Label
        EpqButton                       matlab.ui.control.Button
        EpdqButton                      matlab.ui.control.Button
        EddqButton                      matlab.ui.control.Button
        EpddqButton                     matlab.ui.control.Button
        GraficasTab                     matlab.ui.container.Tab
        CloseImage                      matlab.ui.control.Image
        AyudaFlexextPanel               matlab.ui.container.Panel
        Image1                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        SensorLabel1                    matlab.ui.control.Label
        SensorLabel2                    matlab.ui.control.Label
        SensorLabel3                    matlab.ui.control.Label
        SensorLabel4                    matlab.ui.control.Label
        AdjustmentConfigurationPanel    matlab.ui.container.Panel
        ParmetrodesmoothingLabel        matlab.ui.control.Label
        TrayectoriagrabadaEditFieldLabel  matlab.ui.control.Label
        InputSmoothing                  matlab.ui.control.NumericEditField
        TrayectoriaajustadaEditFieldLabel  matlab.ui.control.Label
        CommandedSmoothing              matlab.ui.control.NumericEditField
        TrayectoriaejecutadaEditFieldLabel  matlab.ui.control.Label
        OutputSmoothing                 matlab.ui.control.NumericEditField
        SeleccionelasgrficasamostrartraselajusteLabel  matlab.ui.control.Label
        PlotPosCartesianaCheckBox       matlab.ui.control.CheckBox
        PlotVelCarteisanaCheckBox       matlab.ui.control.CheckBox
        Plot3DPosCartesianaCheckBox     matlab.ui.control.CheckBox
        En2DLabel                       matlab.ui.control.Label
        En3DLabel                       matlab.ui.control.Label
        PlotErrorPosCartesianaCheckBox  matlab.ui.control.CheckBox
        PlotErrorVelCartesianaCheckBox  matlab.ui.control.CheckBox
        PlotAjusteCheckBox              matlab.ui.control.CheckBox
        PacientePanel                   matlab.ui.container.Panel
        IDSpinnerLabel                  matlab.ui.control.Label
        IDSpinner                       matlab.ui.control.Spinner
        BrazoseleccionadoPanel          matlab.ui.container.Panel
        ArmSwitch                       matlab.ui.control.Switch
        DerechoLabel                    matlab.ui.control.Label
        IzquierdoLabel                  matlab.ui.control.Label
        PlanoPanel                      matlab.ui.container.Panel
        PlaneSwitch                     matlab.ui.control.Switch
        XZLabel                         matlab.ui.control.Label
        YZLabel                         matlab.ui.control.Label
        ConectarPanel                   matlab.ui.container.Panel
        IIWACheckBox                    matlab.ui.control.CheckBox
        DelsysCheckBox                  matlab.ui.control.CheckBox
        FTSensorCheckBox                matlab.ui.control.CheckBox
        AdjustmentConfigurationPanel_2  matlab.ui.container.Panel
        ParmetrodesmoothingLabel_2      matlab.ui.control.Label
        TrayectoriagrabadaEditFieldLabel_2  matlab.ui.control.Label
        InputSmoothing_2                matlab.ui.control.NumericEditField
        TrayectoriaajustadaEditFieldLabel_2  matlab.ui.control.Label
        CommandedSmoothing_2            matlab.ui.control.NumericEditField
        TrayectoriaejecutadaEditFieldLabel_2  matlab.ui.control.Label
        OutputSmoothing_2               matlab.ui.control.NumericEditField
        SeleccionelasgrficasamostrartraselajusteLabel_2  matlab.ui.control.Label
        PlotPosCartesianaCheckBox_2     matlab.ui.control.CheckBox
        PlotVelCarteisanaCheckBox_2     matlab.ui.control.CheckBox
        Plot3DPosCartesianaCheckBox_2   matlab.ui.control.CheckBox
        En2DLabel_2                     matlab.ui.control.Label
        En3DLabel_2                     matlab.ui.control.Label
        PlotErrorPosCartesianaCheckBox_2  matlab.ui.control.CheckBox
        PlotErrorVelCartesianaCheckBox_2  matlab.ui.control.CheckBox
        PlotAjusteCheckBox_2            matlab.ui.control.CheckBox
    end

%% PROPERTIES %%

    %Constant app properties
    properties(Constant)
        TrialsDatabaseFilePath = [fileparts(which(mfilename)),'\trials.mat'];
        TrialsDatabaseBackupFolderPath = fullfile(fileparts(which(mfilename)), 'Backups'); %fullfile(extractBetween(which(mfilename), 1, 'RoboespasMatlab\'), '\RoboespasMatlab\Backups\', extractAfter(fileparts(which(mfilename)), find(fileparts(which(mfilename)) == '\', 1, 'last')));
        ArmReference = ['L', 'R']; % Used to relate L and R with 1 and 2 numbers, possible values of ArmSwitch
        PlaneReference = {'YZ', 'XZ'}; % Used to relate YZ and XZ with 1 and 2 numbers, possible values of PlaneSwitch
        
        DelsysSensorsConnected = 0; %Description
        FTSensorConnected = 0; %Description
        ROSConnected = 1;
        
        TimeWaitBetweenRepetitions = 6 %TODO: Add option to change?
        TrajectorySelectStr = {'Seleccione una trayectoria'};
        MovementSelectStr = {'Seleccione un movimiento'};
        
        MovementMap = containers.Map({'Flexión-Extensión Codo'}, {'flexext'});
        MaxSensors = 6;
        
        DefaultVelocity = 15; % Repeat all trajectories at 30º/s unless slider is changed
    end
    
    %Variable non-observable properties
    properties (Access = private)
        IIWAControlObject
        FTSensorObject
        DelsysSensorsObject

        TrialsFile
        ExecutionTimes = {};
    end
    
    %Variable observable app properties
    properties (SetObservable)
        Arm % May be 'L' or 'R' 
        IDPatient % Description
        NameMovement
        WorkingPlane = 'YZ' % May be 'YZ' or 'XZ'
    end
    
%% FUNCTIONS %%

    %Static functions -> Handle property changed events
    methods (Static)
        function handlePropEvents(src, event)
            switch src.Name
                case 'Arm'
                    event.AffectedObject.ArmChanged();
                case 'IDPatient'
                    event.AffectedObject.IDPatientChanged();
                case 'NameMovement'
                    event.AffectedObject.NameMovementChanged();
                case 'WorkingPlane'
                    event.AffectedObject.WorkingPlaneChanged();
                otherwise
                    ME=MException('Roboespas:HandleError', 'handlePropEvents received a variable whose callback is not defined');
                    throw(ME);
            end
        end
    end
    
    %Created functions
    methods (Access = private)
        
        % Property changed event functions
        function ArmChanged(app)
            app.ArmSwitch.Value=num2str(app.ArmId());
            if (app.Arm=='L')
                app.IzquierdoLabel.Enable='on';
                app.DerechoLabel.Enable='off';
            elseif(app.Arm=='R')
                app.IzquierdoLabel.Enable='off';
                app.DerechoLabel.Enable='on';
            else
                ME=MException('Roboespas:WrongArmValue', '"Arm" value must be either "L" or "R".');
                throw(ME);
            end
        end
        
        function IDPatientChanged(app)
            if (isnumeric(app.IDPatient))
                app.IDSpinner.Value=app.IDPatient;
            else
                ME=MException('Roboespas:WrongIDPatientValue', '"IDPatient" value must be numeric.');
                throw(ME);
            end
        end
        
        function NameMovementChanged(app)
            app.IIWAControlObject.SetMovement(app.NameMovement);
            app.DelsysSensorsObject.SetMovement(app.NameMovement);
            for i = 1:app.MaxSensors
                label = sprintf('Sensor%dLabel', i);
                posEditField = sprintf('Sensor%dpos', i);
                if(i<=app.DelsysSensorsObject.NumSensors)
                    app.(label).Text = ['Sensor ', num2str(i), ': ', app.DelsysSensorsObject.SensorsMuscles{1,i}];
                    app.(posEditField).Visible = 'on';
                    app.(label).Visible = 'on';
                else
                    app.(label).Visible = 'off';
                    app.(posEditField).Visible = 'off';
                end
            end
            app.SaveSensorsPosition();
        end
        
        function WorkingPlaneChanged(app)
            app.PlaneSwitch.Value=num2str(app.PlaneId());
            if (strcmp(app.WorkingPlane,'YZ'))
                app.YZLabel.Enable='on';
                app.XZLabel.Enable='off';
            elseif(strcmp(app.WorkingPlane,'XZ'))
                app.YZLabel.Enable='off';
                app.XZLabel.Enable='on';
            else
                ME=MException('Roboespas:WrongWorkingPlaneValue', '"WorkingPlane" value must be either "YZ" or "XZ".');
                throw(ME);
            end
        end
        
        % Update Dropdowns functions
        function UpdateInputDropDown(app)
            try
                app.InputDropDown.Items=[app.TrajectorySelectStr, app.IIWAControlObject.DataInputList();];
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
  
        function UpdateAdjustedDropDown(app)
            try
                app.AdjustedDropDown.Items=[app.TrajectorySelectStr, app.IIWAControlObject.DataCommandedList()];
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function UpdateMovementDropDown(app)
            try
                app.MovementDropDown.Items = [app.MovementSelectStr, keys(app.MovementMap)];
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');                
            end
        end
        
        %Get id from Arm string
        function id = ArmId(app)
            id=find(app.ArmReference==app.Arm);
        end
        
        %Get id from Plane string
        function id = PlaneId(app)
            id = find(strcmp(app.PlaneReference,app.WorkingPlane));
        end
        

        function SaveTrial(app, i_trial)
            try
                tic
                %trialsFile = matfile(app.TrialsDatabaseFilePath, 'Writable', true);
                if (~isempty(who(app.TrialsFile)))
                    ntrial=size(app.TrialsFile.Trials,1);
                    IDTrial=ntrial+1;
                else
                    IDTrial=1;
                end
                Trial.IDTrial = IDTrial;
                Trial.IDPatient = app.IDPatient;
                Trial.Conditions.NameData = app.IIWAControlObject.NameData;
                Trial.Conditions.Arm = app.Arm;
                Trial.Conditions.WorkingPlane = app.WorkingPlane;
                Trial.Conditions.DateCaptured = app.IIWAControlObject.CapturedDate;
                Trial.Conditions.DateExecuted = datestr(now, 'yymmdd_HHMMSS');
                Trial.Trajectory=app.IIWAControlObject.GetStruct(i_trial);
                Trial.FTSensor=app.FTSensorObject.GetStruct(i_trial);
                Trial.DelsysSensors=app.DelsysSensorsObject.GetStruct(i_trial);
                
                if (~isempty(app.ExecutionTimes))
                    Trial.ExecutionTimes = app.ExecutionTimes{i_trial}; 
                else
                    Trial.ExecutionTimes=[];
                end
                app.TrialsFile.Trials(IDTrial,1) = Trial;
                disp(['Salvado el trial ', num2str(IDTrial)]);
                saveEveryNTrials=1;
                if (mod(IDTrial,saveEveryNTrials) == 0) %Save backup every trial
                    TrialsBackupFilePath = [app.TrialsDatabaseBackupFolderPath{1}, 'trials', datestr(now, '_yyyy_mm_dd_HH_MM'), '.mat'];
                    [status, msg] = copyfile(app.TrialsDatabaseFilePath, TrialsBackupFilePath, 'f');
                    if (status~=1)
                        uialert(app.UIFigure, msg, '');
                    else
                        %uialert(app.UIFigure, ['Backup saved in', TrialsBackupFilePath], '', 'Icon', 'success');
                    end
                end
                toc
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function SetCurrentSliderVelocity(app)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Ajustando trayectoria a la velocidad requerida');
            try
                v=round(app.VelocidadSlider.Value/app.IIWAControlObject.InputAngularVelocity,2);
                prg.Message=['Ajustando trayectoria a velocidad ', num2str(app.VelocidadSlider.Value), char(176), '/s'];
                app.IIWAControlObject.ChangeVelocity(v);
                app.VelocidadLabel.Text=[num2str(round(app.VelocidadSlider.Value,2)), ' ', char(176), '/s'];
                app.PosicionInicialRepetirButton.Enable='off';
                app.EjecutarTrayectoriaButton.Enable='off';
                app.PosicionInicialVacioButton.Enable='on';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function SaveSensorsPosition(app)
            posArray = zeros(app.DelsysSensorsObject.NumSensors,3);
            for i = 1:app.DelsysSensorsObject.NumSensors
                posEditField = sprintf('Sensor%dpos', i);
                value = app.(posEditField).Value;
                valueArray = str2double(split(erase(value, {'[',']'}),{' ',','}));
                posArray(i,:) = valueArray(~isnan(valueArray))';
            end
            app.DelsysSensorsObject.SetSensorsPosition(posArray);
        end
        
        function SetAyudaFlexExtPanel(app)
            app.AyudaFlexextPanel.Position = app.TabGroup.Position;
            app.AyudaFlexextPanel.Position(1) = app.TabGroup.Position(3) + 2*13;
            gapx = 13;
            gapy = 10;
            
            app.Image3.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning3.svg'];
            app.Image3.Position(1) = gapx;
            app.Image3.Position(2) = gapy;
            app.Image3.Position(3) = (app.AyudaFlexextPanel.InnerPosition(3) - gapx*4)/2;
            app.Image3.Position(4) = (app.AyudaFlexextPanel.InnerPosition(4) - 2*app.SensorLabel3.Position(4) - 5*gapy)/2;
            app.SensorLabel3.Text = ['Sensor 3: ', app.DelsysSensorsObject.SensorsMuscles{1,3}];
            app.SensorLabel3.Position(1) = app.Image3.Position(1);
            app.SensorLabel3.Position(2) = app.Image3.Position(2) + app.Image3.Position(4) + gapy;
            
            app.Image4.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning4.svg'];
            app.Image4.Position = app.Image3.Position;
            app.Image4.Position(1) = app.Image3.Position(1) + app.Image3.Position(3) + gapx*2;
            app.SensorLabel4.Text = ['Sensor 4: ', app.DelsysSensorsObject.SensorsMuscles{1,4}];
            app.SensorLabel4.Position(1) = app.Image4.Position(1);
            app.SensorLabel4.Position(2) = app.Image4.Position(2) + app.Image4.Position(4) + gapy;
            
            app.Image1.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning1.svg'];
            app.Image1.Position = app.Image3.Position;
            app.Image1.Position(2) = app.Image3.Position(2) + + app.Image3.Position(4) + app.SensorLabel3.Position(4) + gapy*2;
            app.SensorLabel1.Text = ['Sensor 1: ', app.DelsysSensorsObject.SensorsMuscles{1,1}];
            app.SensorLabel1.Position(1) = app.Image1.Position(1);
            app.SensorLabel1.Position(2) = app.Image1.Position(2) + app.Image1.Position(4) + gapy;
            
            app.Image2.ImageSource = [fileparts(which(mfilename)),'\Fotos\FlexExt\ElectrodePositioning2.svg'];
            app.Image2.Position = app.Image1.Position;
            app.Image2.Position(1) = app.Image4.Position(1);
            app.SensorLabel2.Text = ['Sensor 2: ', app.DelsysSensorsObject.SensorsMuscles{1,2}];
            app.SensorLabel2.Position(1) = app.Image2.Position(1);
            app.SensorLabel2.Position(2) = app.Image2.Position(2) + app.Image2.Position(4) + gapy;
        end
   
        function UpdatePanelsPosition(app)
            app.PacientePanel.Position(2) = app.TabGroup.Position(2) + app.TabGroup.Position(4) + 10;
            app.PacientePanel.Position(1) = app.TabGroup.Position(1);
            app.PacientePanel.Position(3) = (app.TabGroup.Position(3)- 3*5)/5;
            drawnow;
            app.IDSpinnerLabel.Position(1) = app.PacientePanel.Position(3)/2 - (app.IDSpinnerLabel.Position(3)+app.IDSpinner.Position(3))/2;
            app.BrazoseleccionadoPanel.Position(2) = app.PacientePanel.Position(2);
            app.BrazoseleccionadoPanel.Position(1) = app.PacientePanel.Position(1) + app.PacientePanel.Position(3) + 5;
            app.BrazoseleccionadoPanel.Position(3) = (app.TabGroup.Position(3)- 3*5)*2/5;
            drawnow;
            app.IzquierdoLabel.Position(1) = app.BrazoseleccionadoPanel.Position(3)/2 - (app.IzquierdoLabel.Position(3) + app.ArmSwitch.Position(3) + app.DerechoLabel.Position(3) + 15)/2;
            app.ArmSwitch.Position(1) = app.IzquierdoLabel.Position(1) + app.IzquierdoLabel.Position(3) + 5;
            app.DerechoLabel.Position(1) = app.ArmSwitch.Position(1) + app.ArmSwitch.Position(3) + 10;
            app.PlanoPanel.Position(2) = app.PacientePanel.Position(2);
            app.PlanoPanel.Position(1) = app.BrazoseleccionadoPanel.Position(1) + app.BrazoseleccionadoPanel.Position(3) + 5;
            app.PlanoPanel.Position(3) = (app.TabGroup.Position(3) - 3*5)/5;
            drawnow;
            app.YZLabel.Position(1) = app.PlanoPanel.Position(3)/2 - (app.YZLabel.Position(3)+app.PlaneSwitch.Position(3)+app.XZLabel.Position(3)+20)/2;
            app.PlaneSwitch.Position(1) = app.YZLabel.Position(1) + app.YZLabel.Position(3) + 10;
            app.XZLabel.Position(1) = app.PlaneSwitch.Position(1) + app.PlaneSwitch.Position(3) + 10;
            app.ConectarPanel.Position(2) = app.PacientePanel.Position(2);
            app.ConectarPanel.Position(1) = app.PlanoPanel.Position(1) + app.PlanoPanel.Position(3) + 5;
            app.ConectarPanel.Position(3) = (app.TabGroup.Position(3) - 3*5)/5;
            drawnow;
            app.IIWACheckBox.Position(1) = app.ConectarPanel.Position(3)/2 - app.FTSensorCheckBox.Position(3)/2;
            app.DelsysCheckBox.Position(1) = app.IIWACheckBox.Position(1);
            app.FTSensorCheckBox.Position(1) = app.IIWACheckBox.Position(1);
            app.CloseImage.Position(1) = app.TabGroup.Position(3) - 125;
            app.CloseImage.Position(2) = app.TabGroup.Position(4) - 35;
            app.ResultsPanel.Position(1) = app.TabGroup.Position(1)-13;
            app.ResultsPanel.Position(2) = app.TabGroup.Position(2)-10;
            app.ResultsPanel.Position(3) = app.TabGroup.Position(3)-26;
            app.ResultsPanel.Position(4) = app.TabGroup.Position(4);
        end
        
        function ShowCheckedPlots(app)
            if app.PlotPosCartesianaCheckBox.Value == 1
                app.IIWAControlObject.PlotCartesianPositionTrajectories('adjust', 1, figure);
            end
            if app.PlotVelCarteisanaCheckBox.Value == 1
                app.IIWAControlObject.PlotCartesianVelocityTrajectories('adjust', 1, figure);
            end
            if app.Plot3DPosCartesianaCheckBox.Value == 1
                app.IIWAControlObject.Plot3DCartesianPositionTrajectories('adjust', 1, figure);
            end
            if app.PlotErrorPosCartesianaCheckBox.Value == 1
                app.IIWAControlObject.PlotCartesianPositionError('adjust', 1, figure);
            end
            if app.PlotErrorVelCartesianaCheckBox.Value == 1
                app.IIWAControlObject.PlotCartesianVelocityError('adjust', 1, figure);
            end
        end
        
        
        function EnablePlotButtons(app, origin, value)
            if value
                state = 'on';
            else
                state = 'off';
            end
            switch(origin)
                case 'iiwa'
                    app.qButton.Enable = state;
                    app.dqButton.Enable = state;
                    app.ddqButton.Enable = state;
                    app.EpqButton.Enable = state;
                    app.EpdqButton.Enable = state;
                    app.EpddqButton.Enable = state;
                    app.xButton.Enable = state;
                    app.dxButton.Enable = state;
                    app.TresDButton.Enable = state;
                    app.EpxButton.Enable = state;
                    app.EpdxButton.Enable = state;
                case 'ft'
                    app.FTButton.Enable = state;
                case 'delsys'
                    app.EMGButton.Enable = state;
                    app.AceleracionesButton.Enable = state;
                    app.VelocidadAngularButton.Enable = state;                    
            end
        end
        
        function EMGrealTimePlot(app, value)
            if(value)
                app.DelsysSensorsObject.StartEMGplot(app.RepetirTab);
            else
                app.DelsysSensorsObject.StopEMGplot();
            end
        end
    end

%% CALLBACKS %%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando');
            try
                %Visible adjustments
                app.ResultsPanel.Visible='off';
                app.AdjustmentConfigurationPanel.Visible='off';
                app.AyudaFlexextPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                
                % Stablish properties that generate events when modified
                addlistener(app,'Arm','PostSet',@app.handlePropEvents);
                addlistener(app,'IDPatient','PostSet',@app.handlePropEvents);
                addlistener(app, 'NameMovement','PostSet',@app.handlePropEvents);
                addlistener(app, 'WorkingPlane', 'PostSet', @app.handlePropEvents);
    
                %Add folders to path
                addpath(genpath(fileparts(which(mfilename))));
                
                %Initialize IIWAControlObject
                app.IIWAControlObject = IIWAControl(0);
                
                %Initialize FTSensorObject
                app.FTSensorObject = FTSensor(0);
                
                %Initialize DelsysSensors
                app.DelsysSensorsObject = DelsysSensors(0);
                
                %Initialize dropdowns
                app.UpdateInputDropDown();
                app.UpdateAdjustedDropDown();
                app.UpdateMovementDropDown();
                
                app.MovementDropDown.Value=app.MovementDropDown.Items{end};
                app.NameMovement=app.MovementMap(app.MovementDropDown.Value);
                app.NameMovementChanged();
                
                %Initialize IDSpinner
                app.TrialsFile = matfile(app.TrialsDatabaseFilePath, 'Writable', true);
                if (~isempty(who(app.TrialsFile)))
                    lastTrial=app.TrialsFile.Trials(end,1);
                    app.IDSpinner.Value=lastTrial.IDPatient;
                else
                    app.IDSpinner.Value=1;
                end
                
                app.IDSpinner.Value;
                %Initialize variables that need initialization
                app.IDPatient=app.IDSpinner.Value;    
                app.VelocidadLabel.Text = [num2str(app.DefaultVelocity), ' ', char(176), '/s'];
                app.VelocidadSlider.Value = app.DefaultVelocity;
                app.CloseImage.ImageSource = [fileparts(which(mfilename)),'\Fotos\Icons\close.png'];
                app.CloseImage.Tag = 'CloseImage';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderChanged(app, event)
            app.SetCurrentSliderVelocity();
        end

        % Value changed function: IDSpinner
        function IDSpinnerChanged(app, event)
            app.IDPatient = app.IDSpinner.Value;
        end

        % Selection change function: TabGroup
        function TabGroupChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if (selectedTab==app.CapturarTab)
                selection = uiconfirm(app.UIFigure,'New patient?','Change IDPatient?', 'Options',{'Yes','No'},'Icon', 'question');
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando lista de trayectorias de entrada');
                UpdateInputDropDown(app);
                if (strcmp(selection,'Yes'))
                    app.IDPatient=app.IDPatient+1;
                end
                close(prg);
            elseif (selectedTab==app.RepetirTab)
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando lista de trayectorias ajustadas');
                UpdateAdjustedDropDown(app);
                close(prg);
            elseif (selectedTab==app.ResultadosTab)
                j = 1;
                for i = 1:max(size(app.GraficasTab.Children))
                    if(~strcmp(app.GraficasTab.Children(j).Tag, 'CloseImage'))
                        delete(app.GraficasTab.Children(j));
                    else
                        j = j+1;
                    end
                    if max(size(app.GraficasTab.Children))==1
                        break;
                    end
                end
            end
            if (strcmp(app.MovementDropDown.Value, app.MovementSelectStr))
                app.TabGroup.SelectedTab=app.InfoSesionTab;
                uialert(app.UIFigure, 'Seleccione un movimiento primero', 'Error');
            end
        end

        % Value changed function: MovementDropDown
        function MovementDropDownChanged(app, event)
            value = app.MovementDropDown.Value;
            try
                if(any(strcmp(keys(app.MovementMap),value)))
                    app.NameMovement = app.MovementMap(value);
                else
                    app.NameMovement = '';
                    for i = 1:app.MaxSensors
                        label = sprintf('Sensor%dLabel', i);
                        posEditField = sprintf('Sensor%dpos', i);
                        app.(label).Text = ['Sensor ', num2str(i)];
                        app.(posEditField).Value = '[0,0,0]';
                        app.(label).Visible = 'on';
                        app.(posEditField).Visible = 'on';
                    end
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: Sensor1pos
        function SensorsPositionChanged(app, event)
            app.SaveSensorsPosition();
        end

        % Value changed function: CommandedSmoothing, 
        % InputSmoothing, OutputSmoothing
        function SmoothingValueChanged(app, event)
            app.IIWAControlObject.SetSmoothingParameters(app.InputSmoothing.Value, app.CommandedSmoothing.Value, app.OutputSmoothing.Value);
        end

        % Value changed function: AyudaButton
        function AyudaButtonValueChanged(app, event)
            value = app.AyudaButton.Value;
            try
                if(value)
                    if (strcmp(app.AdjustmentConfigurationPanel.Visible, 'on'))
                        app.ConfigurarButton.Value = 0;
                        app.ConfigurarAjusteButtonValueChanged();
                        app.TabGroup.Position(3) = app.UIFigure.Position(3) - 2*13;
                        drawnow;
                    end
                    if(isempty(app.NameMovement))
                        app.NameMovement = app.MovementDropDown.Value;
                    end
                    switch app.NameMovement
                        case 'flexext'
                            app.SetAyudaFlexExtPanel();
                            app.AyudaFlexextPanel.Visible = 'on';
                            app.UIFigure.Position(3) = app.TabGroup.Position(1)*3 + app.TabGroup.Position(3) + app.AyudaFlexextPanel.Position(3);
                        otherwise
                            uialert(app.UIFigure,'Por favor, seleccione un movimiento antes de acceder a la ayuda','Warning');
                            app.AyudaButton.Value = 0;
                    end
                else
                    app.AyudaFlexextPanel.Visible = 'off';
                    app.UIFigure.WindowState = 'maximized';
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: ConfigurarButton
        function ConfigurarAjusteButtonValueChanged(app, event)
            value = app.ConfigurarButton.Value;
            try
                app.InputSmoothing.Value = app.IIWAControlObject.InputSmoothing;
                app.CommandedSmoothing.Value = app.IIWAControlObject.CommandedSmoothing;
                app.OutputSmoothing.Value = app.IIWAControlObject.OutputSmoothing;
                if(value)
                    if (strcmp(app.AyudaFlexextPanel.Visible, 'on'))
                        app.AyudaButton.Value = 0;
                        app.AyudaButtonValueChanged();
                        app.TabGroup.Position(3) = app.UIFigure.Position(3) - 2*13;
                        drawnow;
                    end
                    app.AdjustmentConfigurationPanel.Position = app.TabGroup.Position;
                    app.AdjustmentConfigurationPanel.Position(1) = app.TabGroup.Position(3) + 2*13;
                    app.AdjustmentConfigurationPanel.Visible = 'on';
                    app.UIFigure.Position(3) = app.TabGroup.Position(1)*3 + app.TabGroup.Position(3) + app.AdjustmentConfigurationPanel.Position(3);
                else
                    app.AdjustmentConfigurationPanel.Visible = 'off';
                    app.UIFigure.WindowState = 'maximized';
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
            
        end

        % Value changed function: RelaxMusclesButton
        function RelaxMusclesButtonValueChanged(app, event)
            value = app.RelaxMusclesButton.Value;
            if(app.DelsysCheckBox.Value)
                app.EMGrealTimePlot(value);
            else
                app.RelaxMusclesButton.Value = 0;
            end
        end

        % Value changed function: RelaxMusclesButton2
        function RelaxMusclesButton2ValueChanged(app, event)
            value = app.RelaxMusclesButton2.Value;
            if(app.DelsysCheckBox.Value)
                app.EMGrealTimePlot(value);
            else
                app.RelaxMusclesButton2.Value = 0;
            end
        end

        % Button pushed function: IzquierdaButton
        function MoverPrefIzqdaButton(app, event)
            prg = uiprogressdlg(app.UIFigure, 'Message', 'Moviendose a posición de referencia izquierda');
            try
%                 app.IIWAControlObject.SendReferencePositionLeft(app.WorkingPlane);
                app.IIWAControlObject.SendReferencePositionLeft();
                app.Arm='L';
                app.IniciarButton.Enable='on';
                app.FreeXButton.Enable='on';
                app.FreeYButton.Enable='on';
                app.FreeZButton.Enable='on';
                close(prg);
                uialert(app.UIFigure, 'Robot en posición de referencia izquierda', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: DerechaButton
        function MoverPrefDchaButton(app, event)
            prg = uiprogressdlg(app.UIFigure, 'Message', 'Moviendose a posición de referencia derecha');
            try
%                 app.IIWAControlObject.SendReferencePositionRight(app.WorkingPlane);
                app.IIWAControlObject.SendReferencePositionRight();
                app.Arm ='R';
                app.IniciarButton.Enable ='on';
                app.FreeXButton.Enable ='on';
                app.FreeYButton.Enable ='on';
                app.FreeZButton.Enable ='on';
                close(prg);
                uialert(app.UIFigure, 'Robot en posición de referencia derecha', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Selection changed function: CaptureButtonGroup
        function CaptureButtonGroupSelectionChanged(app, event)
            selectedButton = app.CaptureButtonGroup.SelectedObject;
            if (selectedButton == app.CaptureBlockRobotButton)
                prg = uiprogressdlg(app.UIFigure, 'Message', 'Bloqueando robot');
                try
                    app.IIWAControlObject.BlockRobot();
                    app.FinalizarButton.Enable = 'off';
                    app.CaptureBlockRobotButton.Enable = 'off';
                    app.FreeXButton.Enable = 'on';
                    app.FreeYButton.Enable = 'on';
                    app.FreeZButton.Enable = 'on';
                    app.IzquierdaButton.Enable = 'on';
                    app.DerechaButton.Enable = 'on';
                    app.InputDropDown.Enable = 'on';
                    app.AjustarTrayectoriaButton.Enable = 'on';
                    close(prg);
                    uialert(app.UIFigure, 'Robot bloqueado', '', 'Icon', 'success');                    
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            elseif (selectedButton == app.IniciarButton)
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Iniciando captura');
                try
                    app.IIWAControlObject.CaptureStart( );
                    app.CaptureBlockRobotButton.Enable = 'off';
                    app.IniciarButton.Enable = 'off';
                    app.FinalizarButton.Enable = 'on';
                    app.FreeXButton.Enable = 'off';
                    app.FreeYButton.Enable = 'off';
                    app.FreeZButton.Enable = 'off';
                    app.FreeBlockRobotButton.Enable = 'off';
                    app.IzquierdaButton.Enable = 'off';
                    app.DerechaButton.Enable = 'off';
                    app.InputDropDown.Enable = 'off';
                    app.AjustarTrayectoriaButton.Enable = 'off';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            elseif (selectedButton == app.FinalizarButton)
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Finalizando captura');
                try
                    app.CaptureBlockRobotButton.Enable='on';
%                     app.IIWAControlObject.SaveDataInput(app.IDPatient, app.Arm, app.WorkingPlane);
                    app.IIWAControlObject.SaveDataInput(app.IDPatient, app.Arm);
                    app.UpdateInputDropDown();
                    app.InputDropDown.Value=app.IIWAControlObject.NameDataInput;
                    app.FinalizarButton.Enable='off';
                    close(prg);
                    uialert(app.UIFigure, ['Trayectoria grabada guardada en carpeta ', app.IIWAControlObject.NameDataInput], '', 'Icon', "success");
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end                
            end
        end

        % Selection changed function: FreeButtonGroup
        function FreeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FreeButtonGroup.SelectedObject;
            if (selectedButton == app.FreeBlockRobotButton)
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Bloqueando robot');
                try
                    app.IIWAControlObject.BlockRobot();
                    app.FreeBlockRobotButton.Enable='off';
                    app.FreeXButton.Enable='on';
                    app.FreeYButton.Enable='on';
                    app.FreeZButton.Enable='on';
                    app.IniciarButton.Enable='on';
                    app.InputDropDown.Enable='on';
                    app.IzquierdaButton.Enable='on';
                    app.DerechaButton.Enable='on';
                    if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                        app.AjustarTrayectoriaButton.Enable='on';
                    end
                    close(prg);
                    uialert(app.UIFigure, 'Robot bloqueado', '', 'Icon', 'success');
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Liberando robot');
                try
                    if (selectedButton == app.FreeXButton)
                        app.IIWAControlObject.FreeCartesianCoordinate('X');
                    elseif (selectedButton == app.FreeYButton)
                        app.IIWAControlObject.FreeCartesianCoordinate('Y');
                    elseif (selectedButton == app.FreeZButton)
                        app.IIWAControlObject.FreeCartesianCoordinate('Z');
                    end
                    app.FreeXButton.Enable='off';
                    app.FreeYButton.Enable='off';
                    app.FreeZButton.Enable='off';
                    app.FreeBlockRobotButton.Enable='on';
                    app.IniciarButton.Enable='off';
                    app.InputDropDown.Enable='off';
                    app.IzquierdaButton.Enable='off';
                    app.DerechaButton.Enable='off';
                    app.AjustarTrayectoriaButton.Enable='off';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Callback function
        function PlaneButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlaneButtonGroup.SelectedObject;
            try
                plane = strtok(selectedButton.Text, 'Button');
                app.WorkingPlane = plane;
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
        end

        % Value changed function: InputDropDown
        function InputDropDownChanged(app, event)
            if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando trayectoria de entrada');
                try
%                     [app.IDPatient, app.Arm, app.WorkingPlane] = app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                    [app.IDPatient, app.Arm] = app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                    app.AjustarTrayectoriaButton.Enable='on';
                    close(prg);
                    uialert(app.UIFigure, ['Cargada trayectoria capturada ', app.InputDropDown.Value], '', 'Icon', 'success'); 
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                app.AjustarTrayectoriaButton.Enable='off';
            end
        end

        % Value changed function: AdjustedDropDown
        function AdjustedDropDownChanged(app, event)
            if (~strcmp(app.AdjustedDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando trayectoria ajustada');
                try
%                     [app.IDPatient, app.Arm, app.WorkingPlane] = app.IIWAControlObject.LoadCommanded(app.AdjustedDropDown.Value);
                    [app.IDPatient, app.Arm] = app.IIWAControlObject.LoadCommanded(app.AdjustedDropDown.Value);
                    if (~strcmp(app.InputDropDown.Value,app.IIWAControlObject.NameDataInput))
                        prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando trayectoria de entrada');
                        app.InputDropDown.Value=app.IIWAControlObject.NameDataInput;
%                         [app.IDPatient, app.Arm, app.WorkingPlane]=app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                        [app.IDPatient, app.Arm]=app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                        close(prg);
                    end
                    app.SetCurrentSliderVelocity();
                    app.VelocidadSlider.Enable='on';
                    app.VelocidadLabel.Enable='on';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Button pushed function: AjustarTrayectoriaButton
        function AjustarTrayectoriaButtonPushed(app, event)
            if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Ajustando trayectoria');
                try
%                     [app.IDPatient, app.Arm, app.WorkingPlane]=app.IIWAControlObject.BuildDataAdjusted(app.InputDropDown.Value, app.PlotAjusteCheckBox.Value, app.VelocidadSlider.Value);
                    [app.IDPatient, app.Arm]=app.IIWAControlObject.BuildDataAdjusted(app.InputDropDown.Value, app.PlotAjusteCheckBox.Value, app.VelocidadSlider.Value);
                    app.UpdateAdjustedDropDown();
                    app.AdjustedDropDown.Value=app.IIWAControlObject.NameDataCommanded;
                    app.VelocidadSlider.Value = app.DefaultVelocity;
                    app.VelocidadLabel.Text = [num2str(app.VelocidadSlider.Value), ' ', char(176), '/s'];
                    app.IIWAControlObject.ChangeVelocity(round(app.DefaultVelocity/app.IIWAControlObject.InputAngularVelocity, 2));
                    app.PosicionInicialVacioButton.Enable='on';
                    app.VelocidadSlider.Enable='on';
                    app.VelocidadLabel.Enable='on';
                    app.ShowCheckedPlots();
                    close(prg);
                    uialert(app.UIFigure, ['Trayectoria ajustada guardada en carpeta "entrada" de ', app.IIWAControlObject.NameDataCommanded], '', 'Icon', 'success');
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                uialert(app.UIFigure, 'Seleccione una trayectoria primero', 'Error');
            end
        end

        % Button pushed function: PosicionInicialVacioButton
        function PosicionInicialVacioButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWAControlObject.SendInitialPosition();
                app.EjecutarTrayectoriaVacioButton.Enable='on';
                app.RelaxMusclesButton.Enable = 'on';
                close(prg);
                uialert(app.UIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: PosicionInicialRepetirButton
        function PosicionInicialRepetirButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWAControlObject.SendInitialPosition();
                app.EjecutarTrayectoriaButton.Enable='on';
                app.RelaxMusclesButton2.Enable = 'on';
                close(prg);
                uialert(app.UIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EjecutarTrayectoriaVacioButton
        function EjecutarTrayectoriaVacioButtonPushed(app, event)
            if(app.RelaxMusclesButton.Value == 1)
                app.RelaxMusclesButton.Value = 0;
                app.EMGrealTimePlot(app.RelaxMusclesButton.Value);
            end
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve');
            try
                %Inicializar
                app.DelsysSensorsObject.StartSensors();
                app.FTSensorObject.StartSensor();
                
                %Iniciar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensor FT.';
                app.FTSensorObject.StartReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensores Delsys.';
                app.DelsysSensorsObject.StartCapture();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Ejecutando trayectoria!';
                %Ejecutar
%                 [app.IDPatient, app.Arm, app.WorkingPlane] = app.IIWAControlObject.SendTrajectory();
                [app.IDPatient, app.Arm] = app.IIWAControlObject.SendTrajectory();
                %Parar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensores Delsys.';
                app.DelsysSensorsObject.EndReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensor FT.';
                app.FTSensorObject.EndReference();
                %Limpiar captura y parar sensores
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Finalizando...';
                app.IIWAControlObject.EndReference();
                app.FTSensorObject.StopSensor();
                app.DelsysSensorsObject.StopSensors();

                app.FTSensorObject.GetReference();
                
                %GUI update
                app.EjecutarTrayectoriaVacioButton.Enable='off';
                app.PosicionInicialRepetirButton.Enable='on';
                app.RelaxMusclesButton.Enable = 'off';
                close(prg);
                uialert(app.UIFigure, 'Trayectoria ejecutada en vacío el robot', 'Title', 'Icon', 'success');
            catch ME
               uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end       
        end

        % Button pushed function: EjecutarTrayectoriaButton
        function EjecutarTrayectoriaButtonPushed(app, event)
            if(app.RelaxMusclesButton2.Value == 1)
                app.RelaxMusclesButton2.Value = 0;
                app.EMGrealTimePlot(app.RelaxMusclesButton2.Value);
            end
            prg=uiprogressdlg(app.UIFigure, 'Title', ['Ejecutando trayectoria ', app.IIWAControlObject.NameData]);
            try
                app.FTSensorObject.StartSensor(); %Normally lasts for 4-5 seconds
                app.DelsysSensorsObject.StartSensors();
                for i_trial=1:app.RepetitionsSpinner.Value
                    %Inicializar  
                    str_repeticion=['Repetición ', num2str(i_trial), ' de ', num2str(app.RepetitionsSpinner.Value)];
                    if (i_trial~=1)
                        t_wait = app.TimeWaitBetweenRepetitions-toc;
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso, restante: ', num2str(t_wait)];
                        pause(t_wait); %Pause for N seconds discounting the calculations time
                    end
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria'];
                    prg.Value=((i_trial-1)/(app.RepetitionsSpinner.Value*2)); 
                    
                    %Iniciar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensor FT.'];
                    app.FTSensorObject.StartTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.Init=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensores Delsys.'];
                    app.DelsysSensorsObject.StartCapture();
                    %Ejecutar
                    app.ExecutionTimes{i_trial}.Robot.Call=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Moviendo robot.'];
%                     [app.IDPatient, app.Arm, app.WorkingPlane]= app.IIWAControlObject.SendTrajectory();
                    [app.IDPatient, app.Arm]= app.IIWAControlObject.SendTrajectory();
                    app.ExecutionTimes{i_trial}.Robot.End=toc;
                    
                    %Parar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensores Delsys.'];
                    app.DelsysSensorsObject.EndTrial(i_trial);
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensor FT.'];
                    app.FTSensorObject.EndTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.End=toc;

                    %Save the capture but not interpolate the data yet 
                    tic;
                    if (i_trial~=app.RepetitionsSpinner.Value)
                        %Move to initial position
                        prg.Message =[str_repeticion ': Moviendo a posición inicial']; 
                        app.IIWAControlObject.SendInitialPosition();
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso'];
                    end
                    app.IIWAControlObject.SaveDataOutput(i_trial);
                end
                                    
                %Parar captura FT/DelsysSensors
                app.DelsysSensorsObject.StopSensors();
                app.FTSensorObject.StopSensor();

                
                % Salvar resultados
                for i_trial=1:app.RepetitionsSpinner.Value
                    prg.Value=(((i_trial-1)+app.RepetitionsSpinner.Value)/(app.RepetitionsSpinner.Value*2)); 
                    prg.Message=['Interpolando y salvando repetición ', num2str(i_trial), ' de ', num2str(app.RepetitionsSpinner.Value)];
                    app.SaveTrial(i_trial);
                end
                %Actualizar GUI
                if (app.RepetitionsSpinner.Value==1)
                    app.RepetitionPlotSpinner.Limits=[1 2];
                    app.RepetitionPlotSpinner.Value=1;
                    app.RepetitionPlotSpinner.Enable='off';
                else
                    app.RepetitionPlotSpinner.Enable='on';
                    app.RepetitionPlotSpinner.Value=1;
                    app.RepetitionPlotSpinner.Limits=[0, app.RepetitionsSpinner.Value];
                end
                app.ResultsPanel.Visible='on';
                app.EjecutarTrayectoriaButton.Enable='off';
                app.RelaxMusclesButton2.Enable = 'off';
                close(prg); 
                uialert(app.UIFigure, 'Trayectoria ejecutada en el robot', 'Title', 'Icon', 'success', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf);
                app.TabGroup.SelectedTab = app.ResultadosTab;
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: qButton
        function qButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointPositionTrajectories('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dqButton
        function dqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityTrajectories('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ddqButton
        function ddqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointAccelerationTrajectories('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EqButton
        function EqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointPositionRepeatibilityError('results', app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EdqButton
        function EdqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('results', app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EddqButton
        function EddqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('results', app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpqButton
        function EpqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointPositionError('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpdqButton
        function EpdqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityError('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpddqButton
        function EpddqButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointAccelerationError('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionTrajectories('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dxButton
        function dxButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityTrajectories('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ExButton
        function ExButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionRepeatibilityError('results', app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EdxButton
        function EdxButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityRepeatibilityError('results', app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpxButton
        function EpxButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionError('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpdxButton
        function EpdxButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityError('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: TresDButton
        function TresDButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.Plot3DCartesianPositionTrajectories('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FTButton
        function FTButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.FTSensorObject.PlotFT('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: VelocidadAngularButton
        function VelocidadAngularButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.DelsysSensorsObject.PlotGYRO('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EMGButton
        function EMGButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.DelsysSensorsObject.PlotEMG('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: AceleracionesButton
        function AceleracionesButtonPushed(app, event)
            try
                app.TabGroup.SelectedTab = app.GraficasTab;
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.DelsysSensorsObject.PlotACC('results', app.RepetitionPlotSpinner.Value, app.GraficasTab);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                delete(app);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: IIWACheckBox
        function IIWACheckBoxChanged(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando IIWA');
            try
                app.IIWAControlObject = IIWAControl(app.IIWACheckBox.Value);
                app.NameMovementChanged();
                app.UpdateInputDropDown();
                app.UpdateAdjustedDropDown();
                app.EnablePlotButtons('iiwa', app.IIWACheckBox.Value);
                app.CaptureButtonGroup.SelectedObject=app.CaptureBlockRobotButton;
                app.CaptureBlockRobotButton.Enable='off';
                app.IniciarButton.Enable='off';
                app.FinalizarButton.Enable='off';
                app.FreeBlockRobotButton.Enable='off';
                app.FreeXButton.Enable='off';
                app.FreeYButton.Enable='off';
                app.FreeZButton.Enable='off';
                app.IzquierdaButton.Enable='on';
                app.DerechaButton.Enable='on';
                prg.close();
            catch ME
                app.IIWACheckBox.Value = 0;
                prg.close();
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
            
        end

        % Value changed function: DelsysCheckBox
        function DelsysCheckBoxChanged(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando sensores Delsys');
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                app.DelsysSensorsObject = DelsysSensors(app.DelsysCheckBox.Value);
                app.NameMovementChanged();
                app.EnablePlotButtons('delsys', app.DelsysCheckBox.Value);
                prg.close();
            catch ME
                app.DelsysCheckBox.Value = 0;
                prg.close();
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
                
        end

        % Value changed function: FTSensorCheckBox
        function FTSensorCheckBoxChanged(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando sensor F/T');
            try
                app.FTSensorObject = FTSensor(app.FTSensorCheckBox.Value);
                app.EnablePlotButtons('ft', app.FTSensorCheckBox.Value);
                prg.close();
            catch ME
                app.FTSensorCheckBox.Value = 0;
                prg.close();
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
            
        end

        % Value changed function: RepetitionPlotSpinner
        function RepetitionPlotSpinnerValueChanged(app, event)
            value = app.RepetitionPlotSpinner.Value;
            if (value == 0) && (app.IIWACheckBox.Value == 1)
                app.EqButton.Enable = 'on';
                app.EdqButton.Enable = 'on';
                app.EddqButton.Enable = 'on';
                app.ExButton.Enable = 'on';
                app.EdxButton.Enable = 'on';
            else
                app.EqButton.Enable = 'off';
                app.EdqButton.Enable = 'off';
                app.EddqButton.Enable = 'off';
                app.ExButton.Enable = 'off';
                app.EdxButton.Enable = 'off';
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            if((app.AyudaButton.Value == 0) && (app.ConfigurarButton.Value == 0))
                app.TabGroup.Position(1) = 13;
                app.TabGroup.Position(2) = 10;
                app.TabGroup.Position(3) = position(3) - 2*13;
                app.TabGroup.Position(4) = position(4) - app.PacientePanel.Position(4) - 40;
                drawnow;
                app.UpdatePanelsPosition();
            else
                app.TabGroup.Position(1) = 13;
                app.TabGroup.Position(2) = 10;
                app.TabGroup.Position(3) = (position(3) - 3*13)/2;
                app.TabGroup.Position(4) = position(4) - app.PacientePanel.Position(4) - 40;
                drawnow;
                app.UpdatePanelsPosition();
                if app.AyudaButton.Value == 1
                    app.SetAyudaFlexExtPanel();
                elseif app.ConfigurarButton.Value == 1
                    app.AdjustmentConfigurationPanel.Position = app.TabGroup.Position;
                    app.AdjustmentConfigurationPanel.Position(1) = app.TabGroup.Position(3) + 2*13;
                end
            end
        end

        % Image clicked function: CloseImage
        function CloseImageClicked(app, event)
            app.TabGroup.SelectedTab = app.ResultadosTab;
            app.TabGroupChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupChanged, true);
            app.InfoSesionTab = uitab(app.TabGroup);
            app.InfoSesionTab.Title = 'Info Sesión';
            app.MovementDropDown = uidropdown(app.InfoSesionTab);
            app.MovementDropDown.Items = {'Option1', 'Option2', 'Option3', 'Option4'};
            app.MovementDropDown.ValueChangedFcn = createCallbackFcn(app, @MovementDropDownChanged, true);
            app.MovementDropDown.Value = 'Option1';
            app.SeleccionarMovimientoLabel = uilabel(app.InfoSesionTab);
            app.SeleccionarMovimientoLabel.Text = '1. Seleccionar el tipo de movimiento a realizar';
            app.PosicionSensoresLabel = uilabel(app.InfoSesionTab);
            app.PosicionSensoresLabel.Text = '2. Indicar la posición de los sensores en [mm]';
            app.Sensor1pos = uieditfield(app.InfoSesionTab, 'text');
            app.Sensor1pos.ValueChangedFcn = createCallbackFcn(app, @SensorsPositionChanged, true);
            app.Sensor1pos.Tooltip = {'[xxx yyy zzz]'};
            app.Sensor1pos.Value = '[0,0,0]';
            app.Sensor1Label = uilabel(app.InfoSesionTab);
            app.Sensor1Label.Text = 'Sensor 1';
            app.Sensor2pos = uieditfield(app.InfoSesionTab, 'text');
            app.Sensor2pos.Tooltip = {'[xxx yyy zzz]'};
            app.Sensor2pos.Value = '[0,0,0]';
            app.Sensor2Label = uilabel(app.InfoSesionTab);
            app.Sensor2Label.Text = 'Sensor 2';
            app.Sensor3pos = uieditfield(app.InfoSesionTab, 'text');
            app.Sensor3pos.Tooltip = {'[xxx yyy zzz]'};
            app.Sensor3pos.Value = '[0,0,0]';
            app.Sensor3Label = uilabel(app.InfoSesionTab);
            app.Sensor3Label.Text = 'Sensor 3';
            app.Sensor4pos = uieditfield(app.InfoSesionTab, 'text');
            app.Sensor4pos.Tooltip = {'[xxx yyy zzz]'};
            app.Sensor4pos.Value = '[0,0,0]';
            app.Sensor4Label = uilabel(app.InfoSesionTab);
            app.Sensor4Label.Text = 'Sensor 4';
            app.Sensor5pos = uieditfield(app.InfoSesionTab, 'text');
            app.Sensor5pos.Tooltip = {'[xxx yyy zzz]'};
            app.Sensor5pos.Value = '[0,0,0]';
            app.Sensor5Label = uilabel(app.InfoSesionTab);
            app.Sensor5Label.Text = 'Sensor 5';
            app.Sensor6pos = uieditfield(app.InfoSesionTab, 'text');
            app.Sensor6pos.Tooltip = {'[xxx yyy zzz]'};
            app.Sensor6pos.Value = '[0,0,0]';
            app.Sensor6Label = uilabel(app.InfoSesionTab);
            app.Sensor6Label.Text = 'Sensor 6';
            app.AyudaButton = uibutton(app.InfoSesionTab, 'state');
            app.AyudaButton.ValueChangedFcn = createCallbackFcn(app, @AyudaButtonValueChanged, true);
            app.AyudaButton.Text = 'Ayuda';
            app.CapturarTab = uitab(app.TabGroup);
            app.CapturarTab.Title = 'Capturar';
            app.MoveraPosicinInicialdeReferenciaLabel = uilabel(app.CapturarTab);
            app.MoveraPosicinInicialdeReferenciaLabel.Text = '1. Mover a Posición Inicial de Referencia';
            app.PersonalizarPosicinInicialLabel = uilabel(app.CapturarTab);
            app.PersonalizarPosicinInicialLabel.Text = '3. Personalizar Posición Inicial';
            app.GrabarysalvartrayectoriadeflexinLabel = uilabel(app.CapturarTab);
            app.GrabarysalvartrayectoriadeflexinLabel.Text = '4. Grabar y salvar trayectoria de flexión';
            app.IzquierdaButton = uibutton(app.CapturarTab, 'push');
            app.IzquierdaButton.ButtonPushedFcn = createCallbackFcn(app, @MoverPrefIzqdaButton, true);
            app.IzquierdaButton.Text = 'Izquierda';
            app.DerechaButton = uibutton(app.CapturarTab, 'push');
            app.DerechaButton.ButtonPushedFcn = createCallbackFcn(app, @MoverPrefDchaButton, true);
            app.DerechaButton.Text = 'Derecha';
            app.FreeButtonGroup = uibuttongroup(app.CapturarTab);
            app.FreeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreeButtonGroupSelectionChanged, true);
            app.FreeBlockRobotButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeBlockRobotButton.Enable = 'off';
            app.FreeBlockRobotButton.Text = 'Bloquear';
            app.FreeBlockRobotButton.Value = true;
            app.FreeXButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeXButton.Enable = 'off';
            app.FreeXButton.Text = 'Liberar eje X';
            app.FreeYButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeYButton.Enable = 'off';
            app.FreeYButton.Text = 'Liberar eje Y';
            app.FreeZButton = uitogglebutton(app.FreeButtonGroup);
            app.FreeZButton.Enable = 'off';
            app.FreeZButton.Text = 'Liberar eje Z';
            app.CaptureButtonGroup = uibuttongroup(app.CapturarTab);
            app.CaptureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaptureButtonGroupSelectionChanged, true);
            app.CaptureBlockRobotButton = uitogglebutton(app.CaptureButtonGroup);
            app.CaptureBlockRobotButton.Enable = 'off';
            app.CaptureBlockRobotButton.Text = 'Bloquear';
            app.CaptureBlockRobotButton.Value = true;
            app.IniciarButton = uitogglebutton(app.CaptureButtonGroup);
            app.IniciarButton.Enable = 'off';
            app.IniciarButton.Text = 'Iniciar';
            app.FinalizarButton = uitogglebutton(app.CaptureButtonGroup);
            app.FinalizarButton.Enable = 'off';
            app.FinalizarButton.Text = 'Finalizar';
            app.GenerarysalvartrayectoriadeflexoextensinLabel = uilabel(app.CapturarTab);
            app.GenerarysalvartrayectoriadeflexoextensinLabel.Text = '5. Generar y salvar trayectoria de flexoextensión';
            app.AjustarTrayectoriaButton = uibutton(app.CapturarTab, 'push');
            app.AjustarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @AjustarTrayectoriaButtonPushed, true);
            app.AjustarTrayectoriaButton.Enable = 'off';
            app.AjustarTrayectoriaButton.Text = 'Ajustar Trayectoria';
            app.InputDropDown = uidropdown(app.CapturarTab);
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownChanged, true);
            app.ConfigurarButton = uibutton(app.CapturarTab, 'state');
            app.ConfigurarButton.ValueChangedFcn = createCallbackFcn(app, @ConfigurarAjusteButtonValueChanged, true);
            app.ConfigurarButton.Text = 'Configurar';
            app.ConfigurarajustedetrayectoriaLabel = uilabel(app.CapturarTab);
            app.ConfigurarajustedetrayectoriaLabel.Text = '2. Configurar ajuste de trayectoria';
            app.RepetirTab = uitab(app.TabGroup);
            app.RepetirTab.Title = 'Repetir';
            app.ModificarVelocidadLabel = uilabel(app.RepetirTab);
            app.ModificarVelocidadLabel.Text = '2. Modificar Velocidad';
            app.CalibrarSensorFTparaestaTrayectoriaLabel = uilabel(app.RepetirTab);
            app.CalibrarSensorFTparaestaTrayectoriaLabel.Text = '3. Calibrar Sensor F/T para esta Trayectoria';
            app.PosicionInicialVacioButton = uibutton(app.RepetirTab, 'push');
            app.PosicionInicialVacioButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialVacioButtonPushed, true);
            app.PosicionInicialVacioButton.Enable = 'off';
            app.PosicionInicialVacioButton.Text = 'Mandar a la Posición Inicial';
            app.EjecutarTrayectoriaVacioButton = uibutton(app.RepetirTab, 'push');
            app.EjecutarTrayectoriaVacioButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarTrayectoriaVacioButtonPushed, true);
            app.EjecutarTrayectoriaVacioButton.Enable = 'off';
            app.EjecutarTrayectoriaVacioButton.Text = 'Ejecutar Trayectoria en Vacío';
            app.EjecutarTrayectoriaLabel = uilabel(app.RepetirTab);
            app.EjecutarTrayectoriaLabel.Text = '4. Ejecutar Trayectoria';
            app.PosicionInicialRepetirButton = uibutton(app.RepetirTab, 'push');
            app.PosicionInicialRepetirButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialRepetirButtonPushed, true);
            app.PosicionInicialRepetirButton.Enable = 'off';
            app.PosicionInicialRepetirButton.Text = 'Mandar a la Posición Inicial';
            app.EjecutarTrayectoriaButton = uibutton(app.RepetirTab, 'push');
            app.EjecutarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarTrayectoriaButtonPushed, true);
            app.EjecutarTrayectoriaButton.Enable = 'off';
            app.EjecutarTrayectoriaButton.Text = 'Ejecutar Trayectoria';
            app.RepetitionsSpinner = uispinner(app.RepetirTab);
            app.RepetitionsSpinner.Limits = [1 100];
            app.RepetitionsSpinner.RoundFractionalValues = 'on';
            app.RepetitionsSpinner.Value = 1;
            app.VelocidadSlider = uislider(app.RepetirTab);
            app.VelocidadSlider.Limits = [0 210];
            app.VelocidadSlider.MajorTicks = [0 30 60 90 120 150 180 210];
            app.VelocidadSlider.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180', '210'};
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderChanged, true);
            app.VelocidadSlider.Enable = 'off';
            app.VelocidadSlider.Value = 100;
            app.VelocidadLabel = uilabel(app.RepetirTab);
            app.VelocidadLabel.Enable = 'off';
            app.VelocidadLabel.Text = 'º/s';
            app.AdjustedDropDown = uidropdown(app.RepetirTab);
            app.AdjustedDropDown.ValueChangedFcn = createCallbackFcn(app, @AdjustedDropDownChanged, true);
            app.SeleccionartrayectoriaarepetirLabel = uilabel(app.RepetirTab);
            app.SeleccionartrayectoriaarepetirLabel.Text = '1. Seleccionar trayectoria a repetir';
            app.RelaxMusclesButton = uibutton(app.RepetirTab, 'state');
            app.RelaxMusclesButton.ValueChangedFcn = createCallbackFcn(app, @RelaxMusclesButtonValueChanged, true);
            app.RelaxMusclesButton.Enable = 'off';
            app.RelaxMusclesButton.Text = 'Comprobar relajación músculos';
            app.RelaxMusclesButton2 = uibutton(app.RepetirTab, 'state');
            app.RelaxMusclesButton2.ValueChangedFcn = createCallbackFcn(app, @RelaxMusclesButton2ValueChanged, true);
            app.RelaxMusclesButton2.Enable = 'off';
            app.RelaxMusclesButton2.Text = 'Comprobar relajación músculos';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.ResultsPanel = uipanel(app.ResultadosTab);
            app.TrayectoriaLabel = uilabel(app.ResultsPanel);
            app.TrayectoriaLabel.Text = 'Trayectoria';
            app.ArticularLabel = uilabel(app.ResultsPanel);
            app.ArticularLabel.Text = 'Articular';
            app.qButton = uibutton(app.ResultsPanel, 'push');
            app.qButton.ButtonPushedFcn = createCallbackFcn(app, @qButtonPushed, true);
            app.qButton.Enable = 'off';
            app.qButton.Tooltip = {'Posición articular'};
            app.qButton.Text = 'q';
            app.dqButton = uibutton(app.ResultsPanel, 'push');
            app.dqButton.ButtonPushedFcn = createCallbackFcn(app, @dqButtonPushed, true);
            app.dqButton.Enable = 'off';
            app.dqButton.Tooltip = {'Velocidad articular'};
            app.dqButton.Text = 'dq';
            app.EqButton = uibutton(app.ResultsPanel, 'push');
            app.EqButton.ButtonPushedFcn = createCallbackFcn(app, @EqButtonPushed, true);
            app.EqButton.Enable = 'off';
            app.EqButton.Tooltip = {'Error de repetibilidad de posición articular entre trayectorias ejecutadas'};
            app.EqButton.Text = 'E(q)';
            app.EdqButton = uibutton(app.ResultsPanel, 'push');
            app.EdqButton.ButtonPushedFcn = createCallbackFcn(app, @EdqButtonPushed, true);
            app.EdqButton.Enable = 'off';
            app.EdqButton.Tooltip = {'Error de repetibilidad de velocidad articular entre trayectorias ejecutadas'};
            app.EdqButton.Text = 'E(dq)';
            app.CartesianaLabel = uilabel(app.ResultsPanel);
            app.CartesianaLabel.Text = 'Cartesiana';
            app.xButton = uibutton(app.ResultsPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Enable = 'off';
            app.xButton.Tooltip = {'Posición cartesiana'};
            app.xButton.Text = 'x';
            app.ExButton = uibutton(app.ResultsPanel, 'push');
            app.ExButton.ButtonPushedFcn = createCallbackFcn(app, @ExButtonPushed, true);
            app.ExButton.Enable = 'off';
            app.ExButton.Tooltip = {'Error de repetibilidad de posición cartesiana entre trayectorias ejecutadas'};
            app.ExButton.Text = 'E(x)';
            app.EdxButton = uibutton(app.ResultsPanel, 'push');
            app.EdxButton.ButtonPushedFcn = createCallbackFcn(app, @EdxButtonPushed, true);
            app.EdxButton.Enable = 'off';
            app.EdxButton.Tooltip = {'Error de repetibilidad de velocidad cartesiana entre trayectorias ejecutadas'};
            app.EdxButton.Text = 'E(dx)';
            app.TresDButton = uibutton(app.ResultsPanel, 'push');
            app.TresDButton.ButtonPushedFcn = createCallbackFcn(app, @TresDButtonPushed, true);
            app.TresDButton.Enable = 'off';
            app.TresDButton.Tooltip = {'Posición cartesiana en 3D'};
            app.TresDButton.Text = '3D';
            app.dxButton = uibutton(app.ResultsPanel, 'push');
            app.dxButton.ButtonPushedFcn = createCallbackFcn(app, @dxButtonPushed, true);
            app.dxButton.Enable = 'off';
            app.dxButton.Tooltip = {'Velocidad cartesiana'};
            app.dxButton.Text = 'dx';
            app.SensorFuerzaParLabel = uilabel(app.ResultsPanel);
            app.SensorFuerzaParLabel.Text = 'Sensor Fuerza Par';
            app.FTButton = uibutton(app.ResultsPanel, 'push');
            app.FTButton.ButtonPushedFcn = createCallbackFcn(app, @FTButtonPushed, true);
            app.FTButton.Enable = 'off';
            app.FTButton.Text = 'FT';
            app.SensoresDelsysLabel = uilabel(app.ResultsPanel);
            app.SensoresDelsysLabel.Text = 'Sensores Delsys';
            app.EMGButton = uibutton(app.ResultsPanel, 'push');
            app.EMGButton.ButtonPushedFcn = createCallbackFcn(app, @EMGButtonPushed, true);
            app.EMGButton.Enable = 'off';
            app.EMGButton.Text = 'EMG';
            app.AceleracionesButton = uibutton(app.ResultsPanel, 'push');
            app.AceleracionesButton.ButtonPushedFcn = createCallbackFcn(app, @AceleracionesButtonPushed, true);
            app.AceleracionesButton.Enable = 'off';
            app.AceleracionesButton.Text = 'Aceleraciones';
            app.VelocidadAngularButton = uibutton(app.ResultsPanel, 'push');
            app.VelocidadAngularButton.ButtonPushedFcn = createCallbackFcn(app, @VelocidadAngularButtonPushed, true);
            app.VelocidadAngularButton.Enable = 'off';
            app.VelocidadAngularButton.Text = 'Velocidad Angular';
            app.ddqButton = uibutton(app.ResultsPanel, 'push');
            app.ddqButton.ButtonPushedFcn = createCallbackFcn(app, @ddqButtonPushed, true);
            app.ddqButton.Enable = 'off';
            app.ddqButton.Tooltip = {'Aceleración articular'};
            app.ddqButton.Text = 'ddq';
            app.SeleccionelarepeticinamostrarLabel = uilabel(app.ResultsPanel);
            app.SeleccionelarepeticinamostrarLabel.Text = 'Seleccione la repetición a mostrar';
            app.RepetitionPlotSpinner = uispinner(app.ResultsPanel);
            app.RepetitionPlotSpinner.Limits = [1 10];
            app.RepetitionPlotSpinner.RoundFractionalValues = 'on';
            app.RepetitionPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @RepetitionPlotSpinnerValueChanged, true);
            app.RepetitionPlotSpinner.Tooltip = {'Seleccione ''0'' para comparar todas las repeticiones.'};
            app.RepetitionPlotSpinner.Value = 1;
            app.EpxButton = uibutton(app.ResultsPanel, 'push');
            app.EpxButton.ButtonPushedFcn = createCallbackFcn(app, @EpxButtonPushed, true);
            app.EpxButton.Enable = 'off';
            app.EpxButton.Tooltip = {'Error de posición cartesiana entre la trayectoria comandada y la ejecutada'};
            app.EpxButton.Text = 'Ep(x)';
            app.EpdxButton = uibutton(app.ResultsPanel, 'push');
            app.EpdxButton.ButtonPushedFcn = createCallbackFcn(app, @EpdxButtonPushed, true);
            app.EpdxButton.Enable = 'off';
            app.EpdxButton.Tooltip = {'Error de velocidad cartesiana entre la trayectoria comandada y la ejecutada'};
            app.EpdxButton.Text = 'Ep(dx)';
            app.ExactitudLabel = uilabel(app.ResultsPanel);
            app.ExactitudLabel.Text = 'Exactitud:';
            app.PrecisinLabel = uilabel(app.ResultsPanel);
            app.PrecisinLabel.Text = 'Precisión:';
            app.ExactitudLabel_2 = uilabel(app.ResultsPanel);
            app.ExactitudLabel_2.Text = 'Exactitud:';
            app.PrecisinLabel_2 = uilabel(app.ResultsPanel);
            app.PrecisinLabel_2.Text = 'Precisión:';
            app.EpqButton = uibutton(app.ResultsPanel, 'push');
            app.EpqButton.ButtonPushedFcn = createCallbackFcn(app, @EpqButtonPushed, true);
            app.EpqButton.Enable = 'off';
            app.EpqButton.Tooltip = {'Error de posición articular entre la trayectoria comandada y la ejecutada'};
            app.EpqButton.Text = 'Ep(q)';
            app.EpdqButton = uibutton(app.ResultsPanel, 'push');
            app.EpdqButton.ButtonPushedFcn = createCallbackFcn(app, @EpdqButtonPushed, true);
            app.EpdqButton.Enable = 'off';
            app.EpdqButton.Tooltip = {'Error de velocidad articular entre la trayectoria comandada y la ejecutada'};
            app.EpdqButton.Text = 'Ep(dq)';
            app.EddqButton = uibutton(app.ResultsPanel, 'push');
            app.EddqButton.ButtonPushedFcn = createCallbackFcn(app, @EddqButtonPushed, true);
            app.EddqButton.Enable = 'off';
            app.EddqButton.Tooltip = {'Error de repetibilidad de aceleración articular entre trayectorias ejecutadas'};
            app.EddqButton.Text = 'E(ddq)';
            app.EpddqButton = uibutton(app.ResultsPanel, 'push');
            app.EpddqButton.ButtonPushedFcn = createCallbackFcn(app, @EpddqButtonPushed, true);
            app.EpddqButton.Enable = 'off';
            app.EpddqButton.Tooltip = {'Error de aceleración articular entre la trayectoria comandada y la ejecutada'};
            app.EpddqButton.Text = 'Ep(ddq)';
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Gráficas';
            app.CloseImage = uiimage(app.GraficasTab);
            app.CloseImage.ImageClickedFcn = createCallbackFcn(app, @CloseImageClicked, true);
            app.AyudaFlexextPanel = uipanel(app.UIFigure);
            app.AyudaFlexextPanel.Title = 'Colocación Sensores Flexión-Extensión Codo en brazo derecho';
            app.Image1 = uiimage(app.AyudaFlexextPanel);
            app.Image2 = uiimage(app.AyudaFlexextPanel);
            app.Image3 = uiimage(app.AyudaFlexextPanel);
            app.Image4 = uiimage(app.AyudaFlexextPanel);
            app.SensorLabel1 = uilabel(app.AyudaFlexextPanel);
            app.SensorLabel1.Text = ' Sensor 1';
            app.SensorLabel2 = uilabel(app.AyudaFlexextPanel);
            app.SensorLabel2.Text = ' Sensor 2';
            app.SensorLabel3 = uilabel(app.AyudaFlexextPanel);
            app.SensorLabel3.Text = ' Sensor 3';
            app.SensorLabel4 = uilabel(app.AyudaFlexextPanel);
            app.SensorLabel4.Text = ' Sensor 4';
            app.AdjustmentConfigurationPanel = uipanel(app.UIFigure);
            app.AdjustmentConfigurationPanel.Title = 'Configuración del ajuste de trayectoria';
            app.ParmetrodesmoothingLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.ParmetrodesmoothingLabel.Text = '1. Parámetro de smoothing';
            app.TrayectoriagrabadaEditFieldLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.TrayectoriagrabadaEditFieldLabel.Text = 'Trayectoria grabada';
            app.InputSmoothing = uieditfield(app.AdjustmentConfigurationPanel, 'numeric');
            app.InputSmoothing.ValueChangedFcn = createCallbackFcn(app, @SmoothingValueChanged, true);
            app.TrayectoriaajustadaEditFieldLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.TrayectoriaajustadaEditFieldLabel.Text = 'Trayectoria ajustada';
            app.CommandedSmoothing = uieditfield(app.AdjustmentConfigurationPanel, 'numeric');
            app.CommandedSmoothing.ValueChangedFcn = createCallbackFcn(app, @SmoothingValueChanged, true);
            app.TrayectoriaejecutadaEditFieldLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.TrayectoriaejecutadaEditFieldLabel.Text = 'Trayectoria ejecutada';
            app.OutputSmoothing = uieditfield(app.AdjustmentConfigurationPanel, 'numeric');
            app.OutputSmoothing.ValueChangedFcn = createCallbackFcn(app, @SmoothingValueChanged, true);
            app.SeleccionelasgrficasamostrartraselajusteLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.SeleccionelasgrficasamostrartraselajusteLabel.Text = '2. Seleccione las gráficas a mostrar tras el ajuste';
            app.PlotPosCartesianaCheckBox = uicheckbox(app.AdjustmentConfigurationPanel);
            app.PlotPosCartesianaCheckBox.Text = 'Posición cartesiana XYZABC (ejes individuales)';
            app.PlotVelCarteisanaCheckBox = uicheckbox(app.AdjustmentConfigurationPanel);
            app.PlotVelCarteisanaCheckBox.Text = 'Velocidad cartesiana XYZABC (ejes individuales)';
            app.Plot3DPosCartesianaCheckBox = uicheckbox(app.AdjustmentConfigurationPanel);
            app.Plot3DPosCartesianaCheckBox.Text = 'Posición cartesiana';
            app.En2DLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.En2DLabel.Text = 'En 2D';
            app.En3DLabel = uilabel(app.AdjustmentConfigurationPanel);
            app.En3DLabel.Text = 'En 3D';
            app.PlotErrorPosCartesianaCheckBox = uicheckbox(app.AdjustmentConfigurationPanel);
            app.PlotErrorPosCartesianaCheckBox.Text = 'Error posición cartesiana XYZABC (ejes individuales)';
            app.PlotErrorVelCartesianaCheckBox = uicheckbox(app.AdjustmentConfigurationPanel);
            app.PlotErrorVelCartesianaCheckBox.Text = 'Error velocidad  cartesiana XYZABC (ejes individuales)';
            app.PlotAjusteCheckBox = uicheckbox(app.AdjustmentConfigurationPanel);
            app.PlotAjusteCheckBox.Text = 'Posición cartesiana + ajuste circular';
            app.PlotAjusteCheckBox.Value = true;
            app.PacientePanel = uipanel(app.UIFigure);
            app.PacientePanel.Title = 'Paciente';
            app.IDSpinnerLabel = uilabel(app.PacientePanel);
            app.IDSpinnerLabel.Text = 'ID';
            app.IDSpinner = uispinner(app.PacientePanel);
            app.IDSpinner.Limits = [1 100];
            app.IDSpinner.RoundFractionalValues = 'on';
            app.IDSpinner.ValueChangedFcn = createCallbackFcn(app, @IDSpinnerChanged, true);
            app.IDSpinner.Value = 1;
            app.BrazoseleccionadoPanel = uipanel(app.UIFigure);
            app.BrazoseleccionadoPanel.Title = 'Brazo seleccionado';
            app.ArmSwitch = uiswitch(app.BrazoseleccionadoPanel, 'slider');
            app.ArmSwitch.Items = {'1', '2'};
            app.ArmSwitch.Enable = 'off';
            app.ArmSwitch.Value = '1';
            app.DerechoLabel = uilabel(app.BrazoseleccionadoPanel);
            app.DerechoLabel.Enable = 'off';
            app.DerechoLabel.Text = 'Derecho';
            app.IzquierdoLabel = uilabel(app.BrazoseleccionadoPanel);
            app.IzquierdoLabel.Enable = 'off';
            app.IzquierdoLabel.Text = 'Izquierdo';
            app.PlanoPanel = uipanel(app.UIFigure);
            app.PlanoPanel.Title = 'Plano';
            app.PlaneSwitch = uiswitch(app.PlanoPanel, 'slider');
            app.PlaneSwitch.Items = {'1', '2'};
            app.PlaneSwitch.Enable = 'off';
            app.PlaneSwitch.Value = '1';
            app.XZLabel = uilabel(app.PlanoPanel);
            app.XZLabel.Enable = 'off';
            app.XZLabel.Text = 'XZ';
            app.YZLabel = uilabel(app.PlanoPanel);
            app.YZLabel.Enable = 'off';
            app.YZLabel.Text = 'YZ';
            app.ConectarPanel = uipanel(app.UIFigure);
            app.ConectarPanel.Title = 'Conectar';
            app.IIWACheckBox = uicheckbox(app.ConectarPanel);
            app.IIWACheckBox.ValueChangedFcn = createCallbackFcn(app, @IIWACheckBoxChanged, true);
            app.IIWACheckBox.Text = 'IIWA';
            app.DelsysCheckBox = uicheckbox(app.ConectarPanel);
            app.DelsysCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelsysCheckBoxChanged, true);
            app.DelsysCheckBox.Text = 'Delsys';
            app.FTSensorCheckBox = uicheckbox(app.ConectarPanel);
            app.FTSensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @FTSensorCheckBoxChanged, true);
            app.FTSensorCheckBox.Text = 'FTSensor';
            app.AdjustmentConfigurationPanel_2 = uipanel(app.UIFigure);
            app.AdjustmentConfigurationPanel_2.Title = 'Configuración del ajuste de trayectoria';
            app.ParmetrodesmoothingLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.ParmetrodesmoothingLabel_2.Text = '1. Parámetro de smoothing';
            app.TrayectoriagrabadaEditFieldLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.TrayectoriagrabadaEditFieldLabel_2.Text = 'Trayectoria grabada';
            app.InputSmoothing_2 = uieditfield(app.AdjustmentConfigurationPanel_2, 'numeric');
            app.TrayectoriaajustadaEditFieldLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.TrayectoriaajustadaEditFieldLabel_2.Text = 'Trayectoria ajustada';
            app.CommandedSmoothing_2 = uieditfield(app.AdjustmentConfigurationPanel_2, 'numeric');
            app.TrayectoriaejecutadaEditFieldLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.TrayectoriaejecutadaEditFieldLabel_2.Text = 'Trayectoria ejecutada';
            app.OutputSmoothing_2 = uieditfield(app.AdjustmentConfigurationPanel_2, 'numeric');
            app.SeleccionelasgrficasamostrartraselajusteLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.SeleccionelasgrficasamostrartraselajusteLabel_2.Text = '2. Seleccione las gráficas a mostrar tras el ajuste';
            app.PlotPosCartesianaCheckBox_2 = uicheckbox(app.AdjustmentConfigurationPanel_2);
            app.PlotPosCartesianaCheckBox_2.Text = 'Posición cartesiana XYZABC (ejes individuales)';
            app.PlotVelCarteisanaCheckBox_2 = uicheckbox(app.AdjustmentConfigurationPanel_2);
            app.PlotVelCarteisanaCheckBox_2.Text = 'Velocidad cartesiana XYZABC (ejes individuales)';
            app.Plot3DPosCartesianaCheckBox_2 = uicheckbox(app.AdjustmentConfigurationPanel_2);
            app.Plot3DPosCartesianaCheckBox_2.Text = 'Posición cartesiana';
            app.En2DLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.En2DLabel_2.Text = 'En 2D';
            app.En3DLabel_2 = uilabel(app.AdjustmentConfigurationPanel_2);
            app.En3DLabel_2.Text = 'En 3D';
            app.PlotErrorPosCartesianaCheckBox_2 = uicheckbox(app.AdjustmentConfigurationPanel_2);
            app.PlotErrorPosCartesianaCheckBox_2.Text = 'Error posición cartesiana XYZABC (ejes individuales)';
            app.PlotErrorVelCartesianaCheckBox_2 = uicheckbox(app.AdjustmentConfigurationPanel_2);
            app.PlotErrorVelCartesianaCheckBox_2.Text = 'Error velocidad  cartesiana XYZABC (ejes individuales)';
            app.PlotAjusteCheckBox_2 = uicheckbox(app.AdjustmentConfigurationPanel_2);
            app.PlotAjusteCheckBox_2.Text = 'Posición cartesiana + ajuste circular';
            app.PlotAjusteCheckBox_2.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROBOESPAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end